classdef T2map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2mapUIFigure              matlab.ui.Figure
        DmultisliceT2mappingLabel  matlab.ui.control.Label
        GustavStrijkersAmsterdamUMCVersion20Label  matlab.ui.control.Label
        TulpLogo                   matlab.ui.control.Image
        MessageBox                 matlab.ui.control.TextArea
        BackgroundBox              matlab.ui.control.TextArea
        StatusImage                matlab.ui.control.Image
        statusLabel                matlab.ui.control.Label
        MRSImage                   matlab.ui.control.Image
        TabGroup1                  matlab.ui.container.TabGroup
        DashboardTab               matlab.ui.container.Tab
        LoadMRDfileButton          matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        RecoButton                 matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        ThresholdButton            matlab.ui.control.Button
        Lamp4                      matlab.ui.control.Lamp
        T2FitButton                matlab.ui.control.Button
        Lamp5                      matlab.ui.control.Lamp
        FitProgressGauge           matlab.ui.control.LinearGauge
        Lamp6                      matlab.ui.control.Lamp
        ExitButton                 matlab.ui.control.Button
        ExportDicomButton          matlab.ui.control.Button
        FitparametersPanel         matlab.ui.container.Panel
        FirstsliceLabel            matlab.ui.control.Label
        FirstSliceEditField        matlab.ui.control.NumericEditField
        LastsliceLabel             matlab.ui.control.Label
        LastSliceEditField         matlab.ui.control.NumericEditField
        MinRsquareLabel            matlab.ui.control.Label
        Rsquare                    matlab.ui.control.NumericEditField
        FitProgressLabel           matlab.ui.control.Label
        Lamp7                      matlab.ui.control.Lamp
        ExportGifButton            matlab.ui.control.Button
        AbortFitButton             matlab.ui.control.Button
        RegistrationButton         matlab.ui.control.Button
        Lamp3                      matlab.ui.control.Lamp
        RegProgressGauge           matlab.ui.control.LinearGauge
        RegProgressLabel           matlab.ui.control.Label
        TabGroup3                  matlab.ui.container.TabGroup
        T2mapTab                   matlab.ui.container.Tab
        T2MapLabel                 matlab.ui.control.Label
        ImageSelectorPanel         matlab.ui.container.Panel
        T2MapSlicesSlider          matlab.ui.control.Slider
        SliceEditField_2Label      matlab.ui.control.Label
        T2MapSlicesEditField       matlab.ui.control.NumericEditField
        ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        grayButton                 matlab.ui.control.RadioButton
        autumnButton               matlab.ui.control.RadioButton
        winterButton               matlab.ui.control.RadioButton
        parulaButton               matlab.ui.control.RadioButton
        hotButton                  matlab.ui.control.RadioButton
        T2ScaleEditField           matlab.ui.control.NumericEditField
        T2ScaleMaxLabel            matlab.ui.control.Label
        T2ScaleAutoButton          matlab.ui.control.Button
        T2MapFig                   matlab.ui.control.UIAxes
        T2logPlot                  matlab.ui.control.UIAxes
        T2linearPlot               matlab.ui.control.UIAxes
        T2Image                    matlab.ui.control.Image
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        T2msEditFieldLabel         matlab.ui.control.Label
        T2msEditField              matlab.ui.control.NumericEditField
        M0R2Tab                    matlab.ui.container.Tab
        M0MapLabel                 matlab.ui.control.Label
        M0MapFig                   matlab.ui.control.UIAxes
        R2MapFig                   matlab.ui.control.UIAxes
        R2MapLabel                 matlab.ui.control.Label
        ImageSelectorPanel2        matlab.ui.container.Panel
        T2MapSlicesSlider2         matlab.ui.control.Slider
        SliceEditField_2Label_2    matlab.ui.control.Label
        T2MapSlicesEditField2      matlab.ui.control.NumericEditField
        ColorMapButtonGroup2       matlab.ui.container.ButtonGroup
        grayButton2                matlab.ui.control.RadioButton
        autumnButton2              matlab.ui.control.RadioButton
        winterButton2              matlab.ui.control.RadioButton
        parulaButton2              matlab.ui.control.RadioButton
        hotButton2                 matlab.ui.control.RadioButton
        ColorMapButtonGroup3       matlab.ui.container.ButtonGroup
        grayButton3                matlab.ui.control.RadioButton
        autumnButton3              matlab.ui.control.RadioButton
        winterButton3              matlab.ui.control.RadioButton
        parulaButton3              matlab.ui.control.RadioButton
        hotButton3                 matlab.ui.control.RadioButton
        M0Image                    matlab.ui.control.Image
        R2Image                    matlab.ui.control.Image
        TabGroup2                  matlab.ui.container.TabGroup
        RawimagesTab               matlab.ui.container.Tab
        ImageLabel                 matlab.ui.control.Label
        ImageselectorPanel         matlab.ui.container.Panel
        SlicesSlider               matlab.ui.control.Slider
        EchoesSlider               matlab.ui.control.Slider
        SliceEditFieldLabel        matlab.ui.control.Label
        SlicesEditField            matlab.ui.control.NumericEditField
        EchoEditFieldLabel         matlab.ui.control.Label
        EchoesEditField            matlab.ui.control.NumericEditField
        SegmentationPanel          matlab.ui.container.Panel
        ThresholdSlider            matlab.ui.control.Slider
        ErodeButton                matlab.ui.control.Button
        DilateButton               matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        OpenButton                 matlab.ui.control.Button
        ThresholdEditFieldLabel    matlab.ui.control.Label
        ThresholdEditField         matlab.ui.control.NumericEditField
        ImageIntensityPanel        matlab.ui.container.Panel
        ScaleSliderLabel           matlab.ui.control.Label
        ScaleSlider                matlab.ui.control.Slider
        RawDataFig                 matlab.ui.control.UIAxes
        RawImage                   matlab.ui.control.Image
        TabGroup4                  matlab.ui.container.TabGroup
        SequenceparametersTab      matlab.ui.container.Tab
        TRmsEditFieldLabel         matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TEmsEditFieldLabel         matlab.ui.control.Label
        TEViewField                matlab.ui.control.NumericEditField
        dimxEditFieldLabel         matlab.ui.control.Label
        dimxViewField              matlab.ui.control.NumericEditField
        dimyEditFieldLabel         matlab.ui.control.Label
        dimyViewField              matlab.ui.control.NumericEditField
        SlicesEditFieldLabel       matlab.ui.control.Label
        SlicesViewField            matlab.ui.control.NumericEditField
        EchoesLabel                matlab.ui.control.Label
        EchoesViewField            matlab.ui.control.NumericEditField
        FOVmmEditFieldLabel        matlab.ui.control.Label
        FOVViewField               matlab.ui.control.NumericEditField
        SlicethicknessmmLabel      matlab.ui.control.Label
        SLTViewField               matlab.ui.control.NumericEditField
        AspectratioLabel           matlab.ui.control.Label
        AspectRatioViewField       matlab.ui.control.NumericEditField
        DatafileEditFieldLabel     matlab.ui.control.Label
        DatafileEditField          matlab.ui.control.EditField
    end


    %% --------------------------------------------------------------------------------------
    %
    %   T2 mapping tool
    %   MEMS data
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   November 2020
    %
    %
    %
    %   Version 1.0; aug 2019
    %   - intial version
    %
    %   Version 1.1
    %   - Bug fixes
    %   - Added image registration option
    %
    %   Version 1.2
    %   - Fitting speed dramatically increased
    %
    %   Version 2.0; nov 2020
    %   - updated version
    %
    %
    %% --------------------------------------------------------------------------------------


    properties (Access = public)
       
        % file variables
        appdir                      % Application directory
        olddir                      % Starting directory
        mrd_import_path             % Dicom import path
        dicom_export_path           % Dicom export path
        gif_export_path             % gif export path
        datafile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        
        % checks
        valid_data                  % valid data file true/false
        valid_reco                  % valid reco true/false
        valid_fit                   % valid fit true/false
        
        % scan properties
        dimensions                  % image dimensions
        parameters                  % image paramters
        ne                          % number of echoes
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectratio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        tr                          % repetition time
        te                          % echo time / spacing
        tes                         % all echo times
        orientation                 % phase orientation
      
        % images
        images                      % reconstructed raw images (ne, ns, dimx, dimy)
        threshold                   % segmentation threshold (ns,1)
        mask                        % binary image with segmentation (ns, dimx, dimy)
        scalefactor                 % image intensity scale factor
     
        % fitting
        t2map                       % resulting t2map (ns, dimx, dimy)
        t2cmap                      % T2 color mapping
        m0map                       % resulting m0map (ns, dimx, dimy)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        abortfit                    % abort fit false or true
        
        % colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue


    end
    
    
    methods (Access = private)
        
        
        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end
            
            drawnow;
            
        end
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoButton.Enable = 'off';          else app.RecoButton.Enable = 'on';          end
            if code(3) == 0 app.RegistrationButton.Enable = 'off';  else app.RegistrationButton.Enable = 'on';  end
            if code(4) == 0 app.ThresholdButton.Enable = 'off';     else app.ThresholdButton.Enable = 'on';     end
            if code(5) == 0 app.T2FitButton.Enable = 'off';         else app.T2FitButton.Enable = 'on';         end
            if code(6) == 0 app.ExportDicomButton.Enable = 'off';   else app.ExportDicomButton.Enable = 'on';   end
            if code(7) == 0 app.ExportGifButton.Enable = 'off';     else app.ExportGifButton.Enable = 'on';     end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end
        
        % Switch fit range buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                app.FirstSliceEditField.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
            else
                app.FirstSliceEditField.Enable = 'on';
                app.LastSliceEditField.Enable = 'on';
                app.Rsquare.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0
                app.SlicesEditField.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.EchoesEditField.Enable = 'off';
                app.EchoesSlider.Enable = 'off';
                app.ScaleSlider.Enable = 'off';
            else
                app.SlicesEditField.Enable = 'on';
                app.SlicesSlider.Enable = 'on';
                app.EchoesEditField.Enable = 'on';
                app.EchoesSlider.Enable = 'on';
                app.ScaleSlider.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        % Switch T2 map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0
                app.T2MapSlicesEditField.Enable = 'off';
                app.T2MapSlicesSlider.Enable = 'off';
                app.T2ScaleEditField.Enable = 'off';
                app.T2ScaleAutoButton.Enable = 'off';
                app.grayButton.Enable = 'off';
                app.autumnButton.Enable = 'off';
                app.winterButton.Enable = 'off';
                app.parulaButton.Enable = 'off';
                app.hotButton.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
            else
                app.T2MapSlicesEditField.Enable = 'on';
                app.T2MapSlicesSlider.Enable = 'on';
                app.T2ScaleEditField.Enable = 'on';
                app.T2ScaleAutoButton.Enable = 'on';
                app.grayButton.Enable = 'on';
                app.autumnButton.Enable = 'on';
                app.winterButton.Enable = 'on';
                app.parulaButton.Enable = 'on';
                app.hotButton.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
            end
           
            drawnow;
            
        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            
            % Logo's
            app.TulpLogo.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoButton.Icon = 'reconstruct.gif';
            app.RegistrationButton.Icon = 'sort.gif';
            app.ThresholdButton.Icon = 'threshold.gif';
            app.T2FitButton.Icon = 'exponential.gif';
            app.ExportDicomButton.Icon = 'disk.png';
            app.ExportGifButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T2Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([1,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T2MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T2logPlot.Toolbar.Visible = 'off';
            app.T2linearPlot.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T2MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T2logPlot.Interactions = [];
            app.T2linearPlot.Interactions = [];
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------
        
        
        % Displays the mask in the raw image data
        function PlotRawImages(app)
          
            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');
            
            % Define the colormap
            % Since it is not possible to have 2 colormaps in 1 figure, the
            % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
            cmap1 = 1.5*app.scalefactor*[linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;
                        
            % Background anatomy image
            scale = max(squeeze(app.images(1,app.SlicesEditField.Value,:)));
            [dx,dy] = size(squeeze(app.images(app.EchoesEditField.Value,app.SlicesEditField.Value,:,:)));
            if app.orientation
                xlim(app.RawDataFig,[1,dy]);
                ylim(app.RawDataFig,[1,dx]);
                im = rot90(squeeze(app.images(app.EchoesEditField.Value,app.SlicesEditField.Value,:,:))/scale,-1);
            else
                xlim(app.RawDataFig,[1,dx]);
                ylim(app.RawDataFig,[1,dy]);
                im = squeeze(app.images(app.EchoesEditField.Value,app.SlicesEditField.Value,:,:))/scale;
            end
            background = imshow(im,[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            
            % Overlay image with the mask
            if app.orientation
                imo = rot90(squeeze(2-app.mask(app.SlicesEditField.Value,:,:)),-1);
                alpha = rot90(squeeze(0.7-0.7*app.mask(app.SlicesEditField.Value,:,:)),-1);
            else
                imo = squeeze(2-app.mask(app.SlicesEditField.Value,:,:));
                alpha = squeeze(0.7-0.7*app.mask(app.SlicesEditField.Value,:,:));
            end
            overlay = imshow(imo,[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            set(overlay,'AlphaData',alpha);
            
            % Image aspect ratio
            daspect(app.RawDataFig,[app.AspectRatioViewField.Value 1 1]);
            
            hold(app.RawDataFig,'off');
            drawnow;
            
            % Set some limits now
            app.ThresholdEditField.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
            app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);                                                                       
            app.ThresholdSlider.Limits = [0 max(app.images(1,app.SlicesEditField.Value,:))+1];
            app.ThresholdSlider.Value = app.threshold(app.SlicesEditField.Value);
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)
            
            hold(app.T2MapFig,'on');
            hold(app.M0MapFig,'on');
            hold(app.R2MapFig,'on');
            
            % Plot the T2 map
            [dx,dy] = size(squeeze(app.t2map(app.T2MapSlicesEditField.Value,:,:)));
            if app.orientation
                xlim(app.T2MapFig,[1,dy]);
                ylim(app.T2MapFig,[1,dx]);
                im = rot90(squeeze(app.t2map(app.T2MapSlicesEditField.Value,:,:)),-1);
            else
                xlim(app.T2MapFig,[1,dx]);
                ylim(app.T2MapFig,[1,dy]);
                im = squeeze(app.t2map(app.T2MapSlicesEditField.Value,:,:));
            end
            imshow(im,[0 app.T2ScaleEditField.Value],'Colormap',app.t2cmap,'InitialMagnification','fit','Parent',app.T2MapFig);
            daspect(app.T2MapFig,[app.AspectRatioViewField.Value 1 1]);
            colorbar(app.T2MapFig,'east','Ticks',[]);
            
            % Estimate a suitable scale for the M0 map
            m0scale = round(2*mean(nonzeros(squeeze(app.m0map(app.T2MapSlicesEditField.Value,:)))));
            if isnan(m0scale) m0scale = 100; end
            
            % Plot the M0 map
            [dx,dy] = size(squeeze(app.m0map(app.T2MapSlicesEditField.Value,:,:)));
            if app.orientation
                xlim(app.M0MapFig,[1,dy]);
                ylim(app.M0MapFig,[1,dx]);
                im = rot90(squeeze(app.m0map(app.T2MapSlicesEditField.Value,:,:)),-1);
            else
                xlim(app.M0MapFig,[1,dx]);
                ylim(app.M0MapFig,[1,dy]);
                im = squeeze(app.m0map(app.T2MapSlicesEditField.Value,:,:));
            end
            imshow(im,[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
            daspect(app.M0MapFig,[app.AspectRatioViewField.Value 1 1]);
            
            % Plot the R2 map
            [dx,dy] = size(squeeze(app.r2map(app.T2MapSlicesEditField.Value,:,:)));
            if app.orientation
                xlim(app.R2MapFig,[1,dy]);
                ylim(app.R2MapFig,[1,dx]);
                im = rot90(squeeze(app.r2map(app.T2MapSlicesEditField.Value,:,:)),-1);
            else
                xlim(app.R2MapFig,[1,dx]);
                ylim(app.R2MapFig,[1,dy]);
                im = squeeze(app.r2map(app.T2MapSlicesEditField.Value,:,:));
            end
            imshow(im,[0 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
            daspect(app.R2MapFig,[app.AspectRatioViewField.Value 1 1]);
            colorbar(app.R2MapFig,'east','Ticks',[]);
            
            hold(app.T2MapFig,'off');
            hold(app.M0MapFig,'off');
            hold(app.R2MapFig,'off');
            drawnow;
            
        end % PlotMaps
        
        
        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app)
            
          if app.valid_reco  
              
            cla(app.T2linearPlot);
            cla(app.T2logPlot);
            hold(app.T2linearPlot,'on');
            hold(app.T2logPlot,'on');
            
            xcor = app.XEditField.Value;
            ycor = app.YEditField.Value;
            
            if app.orientation == 1
                t2 = app.t2map(app.T2MapSlicesEditField.Value,app.dimx-xcor+1,ycor);
                m0 = app.m0map(app.T2MapSlicesEditField.Value,app.dimx-xcor+1,ycor);
                datapoints = squeeze(app.images(:,app.T2MapSlicesEditField.Value,app.dimx-xcor+1,ycor));
            else
                t2 = app.t2map(app.T2MapSlicesEditField.Value,ycor,xcor);
                m0 = app.m0map(app.T2MapSlicesEditField.Value,ycor,xcor);
                datapoints = squeeze(app.images(:,app.T2MapSlicesEditField.Value,ycor,xcor));
            end
            
            if (t2>0) && (m0>0)
                
                % fit
                a = 0 : 0.25: max(app.tes(:))+5;
                s = m0*exp(-a./t2);
                
                % plot linear
                scatter(app.T2linearPlot,app.tes,datapoints,'filled');
                plot(app.T2linearPlot,a,s);
                xlim(app.T2linearPlot,[0, max(app.tes)+5]);
                ylim(app.T2linearPlot,[0, 1.1*max(s)]);
               
                % plot log
                scatter(app.T2logPlot,app.tes,log(datapoints),'filled');
                plot(app.T2logPlot,a,log(s));
                xlim(app.T2logPlot,[0, max(app.tes)+5]);
                ylim(app.T2logPlot,[0.99*min(log(s)), 1.01*max(log(s))]);
                
                % plot data point (for debugging)
                % hold(app.T2MapFig,'on');
                % scatter(app.T2MapFig,xcor,ycor,'filled','s','y');
                % hold(app.T2MapFig,'off');
            
                % show T2 value in app
                app.T2msEditField.Value = t2;
                
            end
            
            hold(app.T2linearPlot,'off');
            hold(app.T2logPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------
        
        
        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');
            
            % sequence parameters
            app.ne = app.parameters.NO_ECHOES;
            app.dimx = app.parameters.NO_SAMPLES;
            app.dimy = app.parameters.NO_VIEWS;
            app.fov = app.parameters.FOV;
            app.ns = app.parameters.NO_SLICES;
            app.tr = app.parameters.tr;
            app.te = app.parameters.te;
            app.sl = app.parameters.SLICE_THICKNESS;
            app.tes = app.te * linspace(1,app.ne,app.ne)'; % echo times
            
            % show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TEViewField.Value = app.te;
            app.EchoesViewField.Value = app.ne;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;
            app.SlicesEditField.Limits = [1, app.ns];
            app.SlicesSlider.Limits = [1, app.ns];
            app.T2MapSlicesEditField.Limits = [1, app.ns];
            app.T2MapSlicesSlider.Limits = [1, app.ns];
            app.T2MapSlicesEditField2.Limits = [1, app.ns];
            app.T2MapSlicesSlider2.Limits = [1, app.ns];
            app.EchoesViewField.Value = app.ne;
            app.EchoesEditField.Limits = [1, app.ne];
            app.EchoesSlider.Limits = [1, app.ne];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            app.AspectRatioViewField.Value = app.parameters.FOVf/8;
            app.t2cmap = gray; 
            app.m0cmap = gray; 
            app.r2cmap = gray; 
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = app.ns;
            app.FirstSliceEditField.Limits = [1 app.ns];
            app.LastSliceEditField.Limits = [1 app.ns];
            app.abortfit = false;
            
            % other parameters
            app.scalefactor = 1;
            app.ScaleSlider.Value = app.scalefactor;
            [~,app.tag,~] = fileparts(app.datafile);
            
            % define empty m0 and t2map
            app.m0map = zeros(app.ns,app.dimx,app.dimy);
            app.t2map = zeros(app.ns,app.dimx,app.dimy);
            app.r2map = zeros(app.ns,app.dimx,app.dimy);
            
            % define mask initially with ones
            app.mask = ones(app.ns,app.dimx,app.dimy);
            
            % reset treshold values
            app.threshold = zeros(1,app.ns);
            
        end % SetParameters
    
    
    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)
        
        
        % Text messages in the app
        function TextMessage(app, message)
            
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % main window noname
            app.T2mapUIFigure.Name = '';
            
            % start the parallel pool if it is not running already
            p = gcp('nocreate');
            if isempty(p)
                try
                    parpool(4);
                catch
                    parpool;
                end
            end
            
            % empty file import and export paths
            app.mrd_import_path = '';
            app.dicom_export_path = '';
            app.gif_export_path = '';
                       
            % add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('T2map.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % disable unnecessary warnings
            clc;
            warning off;
            
            % Initialize user interface
            app.InitializeUI;
        
        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Load the T2 data file
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % Select a MRD file
            app.T2mapUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile(strcat(app.mrd_import_path,filesep,'*.MRD'));
            app.T2mapUIFigure.Visible = 'on';
            
            % valid flags
            validfile = false;
            app.valid_data = false;
            app.valid_reco = false;
            app.valid_fit = false;
            
            if ischar(mrdfile)
                % MRD file selected
                app.datafile = [app.mrd_import_path,mrdfile];
                
                % Load the data
                app.TextMessage('Loading images ....');
                [app.data,app.dimensions,app.parameters] = Get_mrd_3D4(app.datafile,'seq','cen');
                
                % valid file
                if isfield(app.parameters,'NO_ECHOES')
                    if app.parameters.NO_ECHOES > 1
                        validfile = true;
                        app.DatafileEditField.Value = mrdfile;
                        SetParameters(app);
                    end
                end
                
                app.orientation = 0;
                if isfield(app.parameters,'PHASE_ORIENTATION')
                    if app.parameters.PHASE_ORIENTATION
                        app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                        app.orientation = 1;
                    else
                        app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                        app.orientation = 0;
                    end
                end
                
            end
            
            if validfile
                % reset buttons
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([1,1,0,0,0,0,0,1]);
                app.valid_data = true;
                app.SetStatus(0);
                
                % reset m0 and t2map
                app.m0map = zeros(app.ns,app.dimx,app.dimy);
                app.t2map = zeros(app.ns,app.dimx,app.dimy);
                app.r2map = zeros(app.ns,app.dimx,app.dimy);
                app.mask = ones(app.ns,app.dimx,app.dimy);
                app.images = zeros(app.ne,app.ns,app.dimx,app.dimy);
                
                % Show the image
                PlotRawImages(app);
                PlotMaps(app);
                
            else
                % not a valid MRD file with navigator
                f = app.T2mapUIFigure;
                uialert(f,'Please select a valid MRD file','Error');
                app.mrd_import_path = '';
                app.valid_data = false;
                app.SetLights([0,0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,0,1]);
                app.SetStatus(2);
            end
            
        end

        % Button pushed function: RecoButton
        function RecoButtonPushed(app, event)
            
            if app.valid_data
            
                % Indicate busy
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
            
                % reset some parameters
                app.scalefactor = 1;
                app.ScaleSlider.Value = app.scalefactor; 
                app.SlicesEditField.Value = 1;
                app.SlicesSlider.Value = 1;
                app.EchoesEditField.Value = 1;
                app.EchoesSlider.Value = 1;
                app.ThresholdEditField.Value = 1;
                app.ThresholdSlider.Value = 1;
                app.T2MapSlicesEditField.Value = 1;
                app.T2MapSlicesSlider.Value = 1;
                
                % reset m0 and t2map
                app.m0map = zeros(app.ns,app.dimx,app.dimy);
                app.t2map = zeros(app.ns,app.dimx,app.dimy);
                app.r2map = zeros(app.ns,app.dimx,app.dimy);
                app.mask = ones(app.ns,app.dimx,app.dimy);
                
                % perform the FFT reconstruction
                tukeyfilter = circtukey2D(app.dimx,app.dimy,0.25);
                for i=1:app.ne
                    for j=1:app.ns
                        app.images(i,j,:,:) = fft2reco(squeeze(app.data(i,j,:,:)));
                    end
                end
                app.valid_reco = true;
                app.valid_fit = false;
                
                % normalize to convenient range
                app.images = round(16384*app.images/max(app.images(:)));
                
                % Show the image
                PlotRawImages(app);
                PlotMaps(app);
                
                % Reset buttons
                app.SetLights([1,1,0,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,0,1]);
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);
          
            end
                
        end

        % Value changed function: EchoesEditField, SlicesEditField
        function SlicesEditFieldValueChanged(app, event)
            
            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.EchoesSlider.Value = app.EchoesEditField.Value;
        
            % Plot the raw image 
            PlotRawImages(app);
            
        end

        % Value changed function: SlicesSlider
        function SlicesSliderValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            
            % Continue with the changed slices edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: EchoesSlider
        function EchoesSliderValueChanged(app, event)
            
            % Adjust the echoes edit field value
            app.EchoesEditField.Value = round(app.EchoesSlider.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: ScaleSlider
        function ScaleSliderValueChanged(app, event)
            
                % change the image intensity scale
                app.scalefactor = app.ScaleSlider.Value;
            
                % replot the images
                PlotRawImages(app)
            
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
            
            success = false;
            
            if app.valid_reco
                
                % Indicate busy
                app.SetLights([1,1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Registering images ....');
                
                % Initialize progress gauge
                app.RegProgressGauge.Value = 0;
                
                % Register all slices to 1st echo time image
                try
                    app.images = register_images(app,app.images);
                    success = true;
                catch
                    success = false;
                end
                
            end
            
            if success
                
                % reset m0 and t2map
                app.m0map = zeros(app.ns,app.dimx,app.dimy);
                app.t2map = zeros(app.ns,app.dimx,app.dimy);
                app.r2map = zeros(app.ns,app.dimx,app.dimy);
                app.mask = ones(app.ns,app.dimx,app.dimy);
                
                % replot the images
                PlotRawImages(app);
                
                % Reset buttons
                app.SetLights([1,1,1,0,0,0,0]);
                app.SetButtons([1,1,1,1,0,0,0,1]);
                app.SetImageSelectionButtons(1);
                app.SetStatus(0);
                app.valid_fit = false;
                
            else
                
                app.TextMessage('ERROR: Something went wrong during registration ...');
                app.SetStatus(2);
                app.valid_fit = false;
                
            end
            
        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            
            if app.valid_reco
                
                % Indicate busy
                app.SetLights([1,1,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');
                
                % Determine automatic threshold using the Otsu method for each slice
                for i=1:app.ns
                    app.threshold(i) = graythresh(mat2gray(app.images(1,i,:,:))) * max(squeeze(app.images(1,i,:)));
                end
                
                % Set the automatic mask for all slices
                app.mask = ones(app.ns,app.dimx,app.dimy);
                for i=1:app.ns
                    app.mask(i,:,:) = apply_threshold_t2(squeeze(app.images(1,i,:,:)),app.threshold(i));
                end
                
                % Display the threshold value for the current slice
                app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value);
                
                % Display the mask in the image
                PlotRawImages(app);
                
                % Indicate busy
                app.SetLights([1,1,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,1,0,0,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(0);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice
            cslice = app.SlicesEditField.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice) = app.ThresholdEditField.Value;
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            % Apply the new threshold
            app.mask(cslice,:,:) = apply_threshold_t2(squeeze(app.images(1,cslice,:,:)),app.threshold(cslice));
            
            % Display the result;
            PlotRawImages(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            app.mask(app.SlicesEditField.Value,:,:) = 1-imerode(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);
            
            % Display the result;
            PlotRawImages(app);
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            app.mask(app.SlicesEditField.Value,:,:) = 1-imdilate(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);
            
            % Display the result;
            PlotRawImages(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Morphological dilation on current slice
            app.mask(app.SlicesEditField.Value,:,:) = 1-imclose(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);
            
            % Display the result;
            PlotRawImages(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Morphological dilation on current slice
            app.mask(app.SlicesEditField.Value,:,:) = 1-imopen(1-squeeze(app.mask(app.SlicesEditField.Value,:,:)),[0 1 0; 1 1 1; 0 1 0]);
            
            % Display the result;
            PlotRawImages(app);
            
        end

        % Button pushed function: T2FitButton
        function T2FitButtonPushed(app, event)
            
            if app.valid_reco
                
                app.SetLights([1,1,2,1,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T2 maps ....');
                
                app.valid_fit = false;
                app.abortfit = false;
                
                % for selected slices
                for i=app.FirstSliceEditField.Value:app.LastSliceEditField.Value
                    
                    % for all y-coordinates
                    for k=1:app.dimy
                        
                        % Stop if abort button is pressed
                        if app.abortfit == true
                            break;
                        end
                        
                        % do the fit for all coordinates, parallel accelerated
                        [app.m0map(i,:,k),app.t2map(i,:,k),app.r2map(i,:,k)] = dotheT2fit_xdim_opt(squeeze(app.images(:,i,:,k)), squeeze(app.mask(i,:,k)), app.tes, app.Rsquare.Value);
                        
                        % Update progress Gauge
                        if mod(k,4)==0
                            app.FitProgressGauge.Value = round(100*((i-app.FirstSliceEditField.Value)*app.dimy+k)/((app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1)*app.dimy));
                            drawnow;
                        end
                        
                        % Plot the slice and T2-map
                        if mod(k,32)==0
                            
                            % Plot Raw images current slice
                            PlotRawImages(app);
                            
                            % Plot T2 map thus far with auto scale
                            % Auto-scale = 2 x mean of the T2 values in the image
                            scale = round(2*mean(nonzeros(squeeze(app.t2map(app.T2MapSlicesEditField.Value,:)))));
                            if isnan(scale) scale = 100; end
                            app.T2ScaleEditField.Value = scale;
                            PlotMaps(app);
                            
                        end
                        
                    end
                    
                end
                
                % Reset buttons
                app.SetLights([1,1,2,1,1,0,0]);
                app.SetButtons([1,1,1,1,1,1,1,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(1);
                app.SetStatus(0);
                app.valid_fit = true;
                
            end
         
        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortfit = true; 
        
        end

        % Value changed function: FirstSliceEditField, 
        % LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            
            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: T2MapSlicesEditField
        function T2MapSlicesEditFieldValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.T2MapSlicesSlider.Value = round(app.T2MapSlicesEditField.Value);
            app.T2MapSlicesEditField2.Value = app.T2MapSlicesEditField.Value;
            app.T2MapSlicesSlider2.Value = app.T2MapSlicesSlider.Value;
            
            % Plot the T2-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: T2MapSlicesEditField2
        function T2MapSlicesEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.T2MapSlicesSlider2.Value = round(app.T2MapSlicesEditField2.Value);
            app.T2MapSlicesEditField.Value = app.T2MapSlicesEditField2.Value;
            app.T2MapSlicesSlider.Value = app.T2MapSlicesSlider2.Value;
            
            % Plot the T2-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: T2MapSlicesSlider
        function T2MapSlicesSliderValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.T2MapSlicesEditField.Value = round(app.T2MapSlicesSlider.Value);
            app.T2MapSlicesEditField2.Value = round(app.T2MapSlicesSlider.Value);
            app.T2MapSlicesSlider2.Value = app.T2MapSlicesSlider.Value;
            
            % Plot the T2-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: T2MapSlicesSlider2
        function T2MapSlicesSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.T2MapSlicesEditField2.Value = round(app.T2MapSlicesSlider2.Value);
            app.T2MapSlicesEditField.Value = round(app.T2MapSlicesSlider2.Value);
            app.T2MapSlicesSlider.Value = app.T2MapSlicesSlider2.Value;
            
            % Plot the T2-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: T2ScaleEditField
        function T2ScaleEditFieldValueChanged(app, event)
            
            % Plot the T2 map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: T2ScaleAutoButton
        function T2ScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the T2 map
            % Auto-scale = 2 x mean of the T2 values in the image
            scale = round(2*mean(nonzeros(squeeze(app.t2map(app.T2MapSlicesEditField.Value,:))))); 
            if isnan(scale) scale = 100; end
            app.T2ScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.t2cmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.t2cmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.t2cmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.t2cmap = parula; 
            end
            
            if app.hotButton.Value == 1
                app.t2cmap = hot; 
            end
            
            % Background should always be black
            app.t2cmap(1,:) = [0 0 0];
            
            % Plot the T2 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup2
        function ColorMapButtonGroup2SelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.hotButton2.Value == 1
                app.m0cmap = hot; 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup3
        function ColorMapButtonGroup3SelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.hotButton3.Value == 1
                app.r2cmap = hot; 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: T2Image
        function T2ImageClicked(app, event)
            
                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T2mapUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                               
                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                ypos = cursorposition(2)-guiposition(2)-imagepos(2);
                
                % Correct for non-square image
                imsize = 313;
                if app.AspectRatioViewField.Value >= 1
                    xcor = (xpos/imsize) * app.dimx;
                    ycor = (ypos/imsize) * app.dimy * app.AspectRatioViewField.Value - (0.5 * (app.AspectRatioViewField.Value-1) * app.dimy);
                else
                    xcor = (xpos/imsize) * app.dimx * (1/app.AspectRatioViewField.Value) - (0.5 * ((1/app.AspectRatioViewField.Value)-1) * app.dimx);     % untested
                    ycor = (ypos/imsize) * app.dimy;
                end
                
                % Round to nearest integer and keep within boundaries
                xcor = round(xcor);
                ycor = round(ycor);
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>app.dimx) = app.dimx;
                ycor(ycor>app.dimy) = app.dimy;
                ycor = app.dimy - ycor + 1;
                
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;
                
                % Plot the results of the fits in the chosen pixel
                app.PlotFits;
                
               
        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            ready = false;
            
            if app.valid_fit
                
                % Indicate busy
                app.SetLights([1,1,2,1,1,0,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                
                % Ask for dicom export directory
                app.T2mapUIFigure.Visible = 'off';
                app.dicom_export_path = uigetdir(app.dicom_export_path,'Dicom export path');
                app.T2mapUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.dicom_export_path)     % user cancel
                    ready = false;
                    app.dicom_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.dicom_export_path);
                    if ~any(Attrib.UserWrite)       % check if directory is writable
                        ready = false;
                    end
                end
                
                % assuming that MRD and Data directory share the same base-directory
                % example: base / MRD / 343 / 8455 /
                % base / data / 343 / DICOM / 8455 /
                dcm_spath{1} = replace(app.mrd_import_path,'MRD','Data');
                dcmpos = strfind(dcm_spath{1},filesep);
                dcmpos = dcmpos(end-1);
                dcm_spath{1} = insertBefore(dcm_spath{1},dcmpos,[filesep,'DICOM']);
                
                % base / data / 343 / DICOM / 8455 / 1 /
                dcm_spath{2} = [dcm_spath{1},'1',filesep];
                
                % base / data / 343 / DICOM / 8455 / 2 /
                % dcm_spath{3} = [dcm_spath{1},'2',filesep];
                
                % Check if one of the directories contains dicom files
                dcm_exist = false;
                app.TextMessage('Searching for DICOM info in the following locations ...');
                for i=1:length(dcm_spath)
                    app.TextMessage(dcm_spath{i});
                    if ~isempty(dir([dcm_spath{i},filesep,'*.dcm']))
                        dcm_path = dcm_spath{i};
                        dcm_exist = true;
                        app.TextMessage('Found DICOM info ...');
                        app.SetStatus(0);
                        break;
                    end
                end
                
                % Dicom export
                if ready
                    
                    if dcm_exist
                        
                        % Dicom export using Dicom information
                        try
                            export_dicom_t2_dcm(app.dicom_export_path,dcm_path,app.m0map,app.t2map,app.tag,app.orientation);
                            app.TextMessage('Export with Dicom information ...');
                        catch
                            ready = false;
                        end
                        
                    else
                        
                        % Dicom export without dicom information
                        try
                            export_dicom_t2(app.dicom_export_path,app.m0map,app.t2map,app.parameters,app.tag,app.orientation);
                            app.TextMessage('WARNING: Export without Dicom information ...');
                            ready = true;
                            app.SetStatus(1);
                        catch
                            ready = false;
                        end
                        
                    end
                    
                end
            
                % Evaluate success
                if ready
                    
                    app.SetLights([1,1,2,1,1,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);
                    
                else
                    
                    app.TextMessage('ERROR: Something went wrong during Dicom export ...');
                    app.SetStatus(2);
                    app.SetLights([1,1,2,1,1,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    
                end
            
            end
        
        end

        % Button pushed function: ExportGifButton
        function ExportGifButtonPushed(app, event)
            
            ready = false;
            
            if app.valid_fit
                
                % Indicate busy
                app.SetLights([1,1,2,1,1,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                
                % Ask for directory
                app.T2mapUIFigure.Visible = 'off';
                app.gif_export_path = uigetdir(app.gif_export_path,'GIF export path');
                app.T2mapUIFigure.Visible = 'on';
                drawnow;
                
                % Check if directory exists
                ready = true;
                if ~ischar(app.gif_export_path)   % user cancel
                    ready = false;
                    app.gif_export_path = '';
                else
                    [~, Attrib] = fileattrib(app.gif_export_path);
                    if ~any(Attrib.UserWrite)   % check if directory is writable
                        ready = false;
                    end
                end
                
                % Export to animated gif
                if ready
                    try
                        app.TextMessage('GIF export ...');
                        export_gif_t2(app.gif_export_path,app.t2map,app.m0map,app.r2map,app.tag,app.T2ScaleEditField.Value,app.t2cmap,app.m0cmap,app.r2cmap,app.AspectRatioViewField.Value,app.orientation);
                    catch
                        ready = false;
                    end
                end
                
            end
            
            % Evaluate success
            if ready
                    
                    app.SetLights([1,1,2,1,1,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);
                    
                else
                    
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.SetStatus(2);
                    app.SetLights([1,1,2,1,1,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    
            end
        
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('T2map.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.T2mapUIFigure = uifigure('Visible', 'off');
            app.T2mapUIFigure.Name = 'UI Figure';
            app.DmultisliceT2mappingLabel = uilabel(app.T2mapUIFigure);
            app.DmultisliceT2mappingLabel.Text = '2D multi slice T2 mapping';
            app.GustavStrijkersAmsterdamUMCVersion20Label = uilabel(app.T2mapUIFigure);
            app.GustavStrijkersAmsterdamUMCVersion20Label.Text = 'Gustav Strijkers, Amsterdam UMC, Version 2.0';
            app.TulpLogo = uiimage(app.T2mapUIFigure);
            app.MessageBox = uitextarea(app.T2mapUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.T2mapUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.T2mapUIFigure);
            app.statusLabel = uilabel(app.T2mapUIFigure);
            app.statusLabel.Text = 'status';
            app.MRSImage = uiimage(app.T2mapUIFigure);
            app.TabGroup1 = uitabgroup(app.T2mapUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoButton = uibutton(app.DashboardTab, 'push');
            app.RecoButton.ButtonPushedFcn = createCallbackFcn(app, @RecoButtonPushed, true);
            app.RecoButton.Enable = 'off';
            app.RecoButton.Text = 'Reco';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.ThresholdButton = uibutton(app.DashboardTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.T2FitButton = uibutton(app.DashboardTab, 'push');
            app.T2FitButton.ButtonPushedFcn = createCallbackFcn(app, @T2FitButtonPushed, true);
            app.T2FitButton.Enable = 'off';
            app.T2FitButton.Text = 'T2 Fit';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Text = 'Exit';
            app.ExportDicomButton = uibutton(app.DashboardTab, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Enable = 'off';
            app.ExportDicomButton.Text = 'Export Dicom';
            app.FitparametersPanel = uipanel(app.DashboardTab);
            app.FitparametersPanel.Title = 'Fit parameters';
            app.FirstsliceLabel = uilabel(app.FitparametersPanel);
            app.FirstsliceLabel.Enable = 'off';
            app.FirstsliceLabel.Text = 'First slice';
            app.FirstSliceEditField = uieditfield(app.FitparametersPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Value = 1;
            app.LastsliceLabel = uilabel(app.FitparametersPanel);
            app.LastsliceLabel.Enable = 'off';
            app.LastsliceLabel.Text = 'Last slice';
            app.LastSliceEditField = uieditfield(app.FitparametersPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Value = 1;
            app.MinRsquareLabel = uilabel(app.FitparametersPanel);
            app.MinRsquareLabel.Enable = 'off';
            app.MinRsquareLabel.Text = 'Min R-square';
            app.Rsquare = uieditfield(app.FitparametersPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Enable = 'off';
            app.Rsquare.Value = 0.8;
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.ExportGifButton = uibutton(app.DashboardTab, 'push');
            app.ExportGifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGifButtonPushed, true);
            app.ExportGifButton.Enable = 'off';
            app.ExportGifButton.Text = 'Export Gif';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Text = 'Abort';
            app.RegistrationButton = uibutton(app.DashboardTab, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Enable = 'off';
            app.RegistrationButton.Text = 'Registration';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.TabGroup3 = uitabgroup(app.T2mapUIFigure);
            app.T2mapTab = uitab(app.TabGroup3);
            app.T2mapTab.Title = 'T2-map';
            app.T2MapLabel = uilabel(app.T2mapTab);
            app.T2MapLabel.Text = 'T2 map';
            app.ImageSelectorPanel = uipanel(app.T2mapTab);
            app.ImageSelectorPanel.Title = 'Image Selector';
            app.T2MapSlicesSlider = uislider(app.ImageSelectorPanel);
            app.T2MapSlicesSlider.Limits = [1 200];
            app.T2MapSlicesSlider.MajorTicks = [];
            app.T2MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesSliderValueChanged, true);
            app.T2MapSlicesSlider.MinorTicks = [];
            app.T2MapSlicesSlider.Value = 1;
            app.SliceEditField_2Label = uilabel(app.ImageSelectorPanel);
            app.SliceEditField_2Label.Text = 'Slice';
            app.T2MapSlicesEditField = uieditfield(app.ImageSelectorPanel, 'numeric');
            app.T2MapSlicesEditField.Limits = [1 Inf];
            app.T2MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesEditFieldValueChanged, true);
            app.T2MapSlicesEditField.Value = 1;
            app.ColorMapButtonGroup = uibuttongroup(app.T2mapTab);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged2, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Value = true;
            app.autumnButton = uiradiobutton(app.ColorMapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColorMapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.T2ScaleEditField = uieditfield(app.ColorMapButtonGroup, 'numeric');
            app.T2ScaleEditField.Limits = [1 1000];
            app.T2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T2ScaleEditFieldValueChanged, true);
            app.T2ScaleEditField.Value = 1;
            app.T2ScaleMaxLabel = uilabel(app.ColorMapButtonGroup);
            app.T2ScaleMaxLabel.Text = 'Max';
            app.T2ScaleAutoButton = uibutton(app.ColorMapButtonGroup, 'push');
            app.T2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T2ScaleAutoButtonPushed, true);
            app.T2ScaleAutoButton.Text = 'Auto';
            app.T2MapFig = uiaxes(app.T2mapTab);
            title(app.T2MapFig, '')
            xlabel(app.T2MapFig, '')
            ylabel(app.T2MapFig, '')
            app.T2MapFig.XTick = [];
            app.T2MapFig.YTick = [];
            app.T2logPlot = uiaxes(app.T2mapTab);
            title(app.T2logPlot, 'Log')
            xlabel(app.T2logPlot, 'TE')
            ylabel(app.T2logPlot, 'log(Signal)')
            app.T2linearPlot = uiaxes(app.T2mapTab);
            title(app.T2linearPlot, 'Linear')
            xlabel(app.T2linearPlot, 'TE')
            ylabel(app.T2linearPlot, 'Signal')
            app.T2Image = uiimage(app.T2mapTab);
            app.T2Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.XEditFieldLabel = uilabel(app.T2mapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.T2mapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.T2mapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.T2mapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.T2msEditFieldLabel = uilabel(app.T2mapTab);
            app.T2msEditFieldLabel.Text = 'T2 (ms)';
            app.T2msEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2msEditField.ValueDisplayFormat = '%.1f';
            app.T2msEditField.Editable = 'off';
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R2';
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            title(app.M0MapFig, '')
            xlabel(app.M0MapFig, '')
            ylabel(app.M0MapFig, '')
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            title(app.R2MapFig, '')
            xlabel(app.R2MapFig, '')
            ylabel(app.R2MapFig, '')
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.ImageSelectorPanel2 = uipanel(app.M0R2Tab);
            app.ImageSelectorPanel2.Title = 'Image Selector';
            app.T2MapSlicesSlider2 = uislider(app.ImageSelectorPanel2);
            app.T2MapSlicesSlider2.Limits = [1 200];
            app.T2MapSlicesSlider2.MajorTicks = [];
            app.T2MapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesSlider2ValueChanged, true);
            app.T2MapSlicesSlider2.MinorTicks = [];
            app.T2MapSlicesSlider2.Value = 1;
            app.SliceEditField_2Label_2 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.T2MapSlicesEditField2 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.T2MapSlicesEditField2.Limits = [1 Inf];
            app.T2MapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesEditField2ValueChanged, true);
            app.T2MapSlicesEditField2.Value = 1;
            app.ColorMapButtonGroup2 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup2SelectionChanged, true);
            app.ColorMapButtonGroup2.Title = 'Color Map';
            app.grayButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.autumnButton2.Text = 'autumn';
            app.winterButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.winterButton2.Text = 'winter';
            app.parulaButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.parulaButton2.Text = 'parula';
            app.hotButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.hotButton2.Text = 'hot';
            app.ColorMapButtonGroup3 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup3SelectionChanged, true);
            app.ColorMapButtonGroup3.Title = 'Color Map';
            app.grayButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Value = true;
            app.autumnButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.autumnButton3.Text = 'autumn';
            app.winterButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.winterButton3.Text = 'winter';
            app.parulaButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.parulaButton3.Text = 'parula';
            app.hotButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.hotButton3.Text = 'hot';
            app.M0Image = uiimage(app.M0R2Tab);
            app.R2Image = uiimage(app.M0R2Tab);
            app.TabGroup2 = uitabgroup(app.T2mapUIFigure);
            app.RawimagesTab = uitab(app.TabGroup2);
            app.RawimagesTab.Title = 'Raw images';
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageselectorPanel = uipanel(app.RawimagesTab);
            app.ImageselectorPanel.Title = 'Image selector';
            app.SlicesSlider = uislider(app.ImageselectorPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @SlicesSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [];
            app.SlicesSlider.Value = 1;
            app.EchoesSlider = uislider(app.ImageselectorPanel);
            app.EchoesSlider.Limits = [1 200];
            app.EchoesSlider.MajorTicks = [];
            app.EchoesSlider.ValueChangedFcn = createCallbackFcn(app, @EchoesSliderValueChanged, true);
            app.EchoesSlider.MinorTicks = [];
            app.EchoesSlider.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageselectorPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SlicesEditField = uieditfield(app.ImageselectorPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.SlicesEditField.Value = 1;
            app.EchoEditFieldLabel = uilabel(app.ImageselectorPanel);
            app.EchoEditFieldLabel.Text = 'Echo';
            app.EchoesEditField = uieditfield(app.ImageselectorPanel, 'numeric');
            app.EchoesEditField.Limits = [1 Inf];
            app.EchoesEditField.ValueDisplayFormat = '%.0f';
            app.EchoesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.EchoesEditField.Value = 1;
            app.SegmentationPanel = uipanel(app.RawimagesTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ImageIntensityPanel = uipanel(app.RawimagesTab);
            app.ImageIntensityPanel.Title = 'Image Intensity';
            app.ScaleSliderLabel = uilabel(app.ImageIntensityPanel);
            app.ScaleSliderLabel.Text = 'Scale';
            app.ScaleSlider = uislider(app.ImageIntensityPanel);
            app.ScaleSlider.Limits = [0 2];
            app.ScaleSlider.MajorTicks = [0 1 2];
            app.ScaleSlider.MajorTickLabels = {'0', '1', '2'};
            app.ScaleSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleSliderValueChanged, true);
            app.ScaleSlider.Value = 0.01;
            app.RawDataFig = uiaxes(app.RawimagesTab);
            title(app.RawDataFig, '')
            xlabel(app.RawDataFig, '')
            ylabel(app.RawDataFig, '')
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.RawImage = uiimage(app.RawimagesTab);
            app.TabGroup4 = uitabgroup(app.T2mapUIFigure);
            app.SequenceparametersTab = uitab(app.TabGroup4);
            app.SequenceparametersTab.Title = 'Sequence parameters';
            app.TRmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TEmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TEViewField.Limits = [0 Inf];
            app.TEViewField.Editable = 'off';
            app.dimxEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimyEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.SlicesEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.EchoesLabel = uilabel(app.SequenceparametersTab);
            app.EchoesLabel.Text = 'Echoes';
            app.EchoesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.EchoesViewField.Limits = [0 Inf];
            app.EchoesViewField.Editable = 'off';
            app.FOVmmEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.FOVmmEditFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.SlicethicknessmmLabel = uilabel(app.SequenceparametersTab);
            app.SlicethicknessmmLabel.Text = 'Slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.AspectratioLabel = uilabel(app.SequenceparametersTab);
            app.AspectratioLabel.Text = 'Aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.1f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Value = 1;
            app.DatafileEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.DatafileEditFieldLabel.Text = 'Datafile';
            app.DatafileEditField = uieditfield(app.SequenceparametersTab, 'text');
            app.DatafileEditField.Editable = 'off';
            % Show the figure after all components are created
            app.T2mapUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T2map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.T2mapUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2mapUIFigure)
        end
    end
end