classdef T2mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2mappUIFigure                matlab.ui.Figure
        Hyperlink                     matlab.ui.control.Hyperlink
        TabGroup4                     matlab.ui.container.TabGroup
        SequenceParametersTab         matlab.ui.container.Tab
        SLTViewField                  matlab.ui.control.NumericEditField
        SLTViewFieldLabel             matlab.ui.control.Label
        SlicesViewField               matlab.ui.control.NumericEditField
        SlicesViewFieldLabel          matlab.ui.control.Label
        DimyViewField                 matlab.ui.control.NumericEditField
        DimyViewFieldLabel            matlab.ui.control.Label
        DimxViewField                 matlab.ui.control.NumericEditField
        DimxViewFieldLabel            matlab.ui.control.Label
        FOVViewField                  matlab.ui.control.NumericEditField
        FOVViewFieldLabel             matlab.ui.control.Label
        AspectRatioViewField          matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel     matlab.ui.control.Label
        DynamicsViewField             matlab.ui.control.NumericEditField
        DynamicsViewFieldLabel        matlab.ui.control.Label
        EchoesViewField               matlab.ui.control.NumericEditField
        EchoesViewFieldLabel          matlab.ui.control.Label
        ESPEditField                  matlab.ui.control.NumericEditField
        ESPEditFieldLabel             matlab.ui.control.Label
        TE0EditField                  matlab.ui.control.NumericEditField
        TE0EditFieldLabel             matlab.ui.control.Label
        TRViewField                   matlab.ui.control.NumericEditField
        TRViewFieldLabel              matlab.ui.control.Label
        DataFileViewField             matlab.ui.control.EditField
        DataFileViewFieldLabel        matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        RawImagesTab                  matlab.ui.container.Tab
        LevelEditField                matlab.ui.control.NumericEditField
        LevelEditFieldLabel           matlab.ui.control.Label
        WindowEditField               matlab.ui.control.NumericEditField
        WindowEditFieldLabel          matlab.ui.control.Label
        AutoLWButton                  matlab.ui.control.StateButton
        LevelSlider                   matlab.ui.control.Slider
        WindowSlider                  matlab.ui.control.Slider
        RawImage                      matlab.ui.control.Image
        RawImageSelectionPanel        matlab.ui.container.Panel
        DynamicsEditFieldLabel        matlab.ui.control.Label
        EchoesEditFieldLabel          matlab.ui.control.Label
        SlicesEditFieldLabel          matlab.ui.control.Label
        DynamicsSlider                matlab.ui.control.Slider
        EchoesSlider                  matlab.ui.control.Slider
        SlicesSlider                  matlab.ui.control.Slider
        DynamicsEditField             matlab.ui.control.NumericEditField
        EchoesEditField               matlab.ui.control.NumericEditField
        SlicesEditField               matlab.ui.control.NumericEditField
        RawImageLabel                 matlab.ui.control.Label
        RawImageMaskSettingsPanel     matlab.ui.container.Panel
        ThresholdEditField            matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        OpenButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        DilateButton                  matlab.ui.control.Button
        ErodeButton                   matlab.ui.control.Button
        ThresholdSlider               matlab.ui.control.Slider
        RawDataFig                    matlab.ui.control.UIAxes
        RecoParametersTab             matlab.ui.container.Tab
        CSregularizationPanel         matlab.ui.container.Panel
        TVdynEditFieldLabel           matlab.ui.control.Label
        TVteEditFieldLabel            matlab.ui.control.Label
        LLRxyzEditFieldLabel          matlab.ui.control.Label
        TVxyzEditFieldLabel           matlab.ui.control.Label
        WVxyzEditFieldLabel           matlab.ui.control.Label
        TVdynEditField                matlab.ui.control.NumericEditField
        TVteEditField                 matlab.ui.control.NumericEditField
        LLRxyzEditField               matlab.ui.control.NumericEditField
        TVxyzEditField                matlab.ui.control.NumericEditField
        WVxyzEditField                matlab.ui.control.NumericEditField
        FilteringPanel                matlab.ui.container.Panel
        DeRingCheckBox                matlab.ui.control.CheckBox
        DeRingCheckBoxLabel           matlab.ui.control.Label
        DeNoiseWindowEditField        matlab.ui.control.NumericEditField
        DeNoiseCheckBox               matlab.ui.control.CheckBox
        DeNoiseCheckBoxLabel          matlab.ui.control.Label
        FitparametersTab              matlab.ui.container.Tab
        DynamicsSelectionPanel        matlab.ui.container.Panel
        LastDynamicEditField          matlab.ui.control.NumericEditField
        LastDynamicEditFieldLabel     matlab.ui.control.Label
        FirstDynamicEditField         matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel    matlab.ui.control.Label
        GoodnessOfFitPanel            matlab.ui.container.Panel
        RsquareEditField              matlab.ui.control.NumericEditField
        RsquareEditFieldLabel         matlab.ui.control.Label
        SliceSelectionPanel           matlab.ui.container.Panel
        LastSliceEditField            matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel       matlab.ui.control.Label
        FirstSliceEditField           matlab.ui.control.NumericEditField
        FirstSliceEditFieldLabel      matlab.ui.control.Label
        EchoSelectionPanel            matlab.ui.container.Panel
        CheckBox_64                   matlab.ui.control.CheckBox
        CheckBox_63                   matlab.ui.control.CheckBox
        CheckBox_62                   matlab.ui.control.CheckBox
        CheckBox_61                   matlab.ui.control.CheckBox
        CheckBox_60                   matlab.ui.control.CheckBox
        CheckBox_59                   matlab.ui.control.CheckBox
        CheckBox_58                   matlab.ui.control.CheckBox
        CheckBox_57                   matlab.ui.control.CheckBox
        CheckBox_56                   matlab.ui.control.CheckBox
        CheckBox_55                   matlab.ui.control.CheckBox
        CheckBox_54                   matlab.ui.control.CheckBox
        CheckBox_53                   matlab.ui.control.CheckBox
        CheckBox_52                   matlab.ui.control.CheckBox
        CheckBox_51                   matlab.ui.control.CheckBox
        CheckBox_50                   matlab.ui.control.CheckBox
        CheckBox_49                   matlab.ui.control.CheckBox
        CheckBox_48                   matlab.ui.control.CheckBox
        CheckBox_47                   matlab.ui.control.CheckBox
        CheckBox_46                   matlab.ui.control.CheckBox
        CheckBox_45                   matlab.ui.control.CheckBox
        CheckBox_44                   matlab.ui.control.CheckBox
        CheckBox_43                   matlab.ui.control.CheckBox
        CheckBox_42                   matlab.ui.control.CheckBox
        CheckBox_41                   matlab.ui.control.CheckBox
        CheckBox_40                   matlab.ui.control.CheckBox
        CheckBox_39                   matlab.ui.control.CheckBox
        CheckBox_38                   matlab.ui.control.CheckBox
        CheckBox_37                   matlab.ui.control.CheckBox
        CheckBox_36                   matlab.ui.control.CheckBox
        CheckBox_35                   matlab.ui.control.CheckBox
        CheckBox_34                   matlab.ui.control.CheckBox
        CheckBox_33                   matlab.ui.control.CheckBox
        CheckBox_32                   matlab.ui.control.CheckBox
        CheckBox_31                   matlab.ui.control.CheckBox
        CheckBox_30                   matlab.ui.control.CheckBox
        CheckBox_29                   matlab.ui.control.CheckBox
        CheckBox_28                   matlab.ui.control.CheckBox
        CheckBox_27                   matlab.ui.control.CheckBox
        CheckBox_26                   matlab.ui.control.CheckBox
        CheckBox_25                   matlab.ui.control.CheckBox
        CheckBox_24                   matlab.ui.control.CheckBox
        CheckBox_23                   matlab.ui.control.CheckBox
        CheckBox_22                   matlab.ui.control.CheckBox
        CheckBox_21                   matlab.ui.control.CheckBox
        CheckBox_20                   matlab.ui.control.CheckBox
        CheckBox_19                   matlab.ui.control.CheckBox
        CheckBox_18                   matlab.ui.control.CheckBox
        CheckBox_17                   matlab.ui.control.CheckBox
        CheckBox_16                   matlab.ui.control.CheckBox
        CheckBox_15                   matlab.ui.control.CheckBox
        CheckBox_14                   matlab.ui.control.CheckBox
        CheckBox_13                   matlab.ui.control.CheckBox
        CheckBox_12                   matlab.ui.control.CheckBox
        CheckBox_11                   matlab.ui.control.CheckBox
        CheckBox_10                   matlab.ui.control.CheckBox
        CheckBox_9                    matlab.ui.control.CheckBox
        CheckBox_8                    matlab.ui.control.CheckBox
        CheckBox_7                    matlab.ui.control.CheckBox
        CheckBox_6                    matlab.ui.control.CheckBox
        CheckBox_5                    matlab.ui.control.CheckBox
        CheckBox_4                    matlab.ui.control.CheckBox
        CheckBox_3                    matlab.ui.control.CheckBox
        CheckBox_2                    matlab.ui.control.CheckBox
        CheckBox_1                    matlab.ui.control.CheckBox
        AppSettingsTab                matlab.ui.container.Tab
        ColorsPanel                   matlab.ui.container.Panel
        ColorsButtonGroup             matlab.ui.container.ButtonGroup
        BlackWhiteButton              matlab.ui.control.RadioButton
        ClassicButton                 matlab.ui.control.RadioButton
        SoundsPanel                   matlab.ui.container.Panel
        AutoSoundsCheckBoxLabel       matlab.ui.control.Label
        AutoSoundsCheckBox            matlab.ui.control.CheckBox
        TabGroup3                     matlab.ui.container.TabGroup
        T2mapTab                      matlab.ui.container.Tab
        LinearLabel                   matlab.ui.control.Label
        LogLabel                      matlab.ui.control.Label
        T2BrushEditField              matlab.ui.control.NumericEditField
        T2BrushEditFieldLabel         matlab.ui.control.Label
        T2ExportRoiDataButton         matlab.ui.control.Button
        T2ROIViewFieldLabel           matlab.ui.control.Label
        T2PixelValueLabel             matlab.ui.control.Label
        T2ROIViewField                matlab.ui.control.EditField
        T2ViewFieldLabel              matlab.ui.control.Label
        T2ViewField                   matlab.ui.control.EditField
        T2ROIEraseButton              matlab.ui.control.Button
        T2ROIbutton                   matlab.ui.control.Button
        T2YEditField                  matlab.ui.control.NumericEditField
        T2YEditFieldLabel             matlab.ui.control.Label
        T2XEditField                  matlab.ui.control.NumericEditField
        T2XEditFieldLabel             matlab.ui.control.Label
        T2Image                       matlab.ui.control.Image
        T2ColormapButtonGroup         matlab.ui.container.ButtonGroup
        T2ScaleAutoButton             matlab.ui.control.Button
        T2ScaleEditField              matlab.ui.control.NumericEditField
        T2ScaleMaxLabel               matlab.ui.control.Label
        naviaButton1                  matlab.ui.control.RadioButton
        parulaButton1                 matlab.ui.control.RadioButton
        winterButton1                 matlab.ui.control.RadioButton
        autumnButton1                 matlab.ui.control.RadioButton
        grayButton1                   matlab.ui.control.RadioButton
        T2ImageSelectionPanel         matlab.ui.container.Panel
        T2MapDynamicsEditFieldLabel   matlab.ui.control.Label
        T2MapSlicesEditFieldLabel     matlab.ui.control.Label
        T2MapDynamicsSlider           matlab.ui.control.Slider
        T2MapDynamicsEditField        matlab.ui.control.NumericEditField
        T2MapSlicesSlider             matlab.ui.control.Slider
        T2MapSlicesEditField          matlab.ui.control.NumericEditField
        T2MapLabel                    matlab.ui.control.Label
        T2linearPlot                  matlab.ui.control.UIAxes
        T2logPlot                     matlab.ui.control.UIAxes
        T2MapFig                      matlab.ui.control.UIAxes
        M0R2Tab                       matlab.ui.container.Tab
        M0BrushEditField              matlab.ui.control.NumericEditField
        M0BrushEditFieldLabel         matlab.ui.control.Label
        M0ROIEraseButton              matlab.ui.control.Button
        M0ROIButton                   matlab.ui.control.Button
        R2Image                       matlab.ui.control.Image
        M0Image                       matlab.ui.control.Image
        R2ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        naviaButton3                  matlab.ui.control.RadioButton
        parulaButton3                 matlab.ui.control.RadioButton
        winterButton3                 matlab.ui.control.RadioButton
        autumnButton3                 matlab.ui.control.RadioButton
        grayButton3                   matlab.ui.control.RadioButton
        M0ColorMapButtonGroup         matlab.ui.container.ButtonGroup
        naviaButton2                  matlab.ui.control.RadioButton
        parulaButton2                 matlab.ui.control.RadioButton
        winterButton2                 matlab.ui.control.RadioButton
        autumnButton2                 matlab.ui.control.RadioButton
        grayButton2                   matlab.ui.control.RadioButton
        M0ImageSelectionPanel         matlab.ui.container.Panel
        M0MapDynamicsEditFieldLabel   matlab.ui.control.Label
        M0MapSlicesEditFieldLabel     matlab.ui.control.Label
        M0MapDynamicsSlider           matlab.ui.control.Slider
        M0MapDynamicsEditField        matlab.ui.control.NumericEditField
        M0MapSlicesSlider             matlab.ui.control.Slider
        M0MapSlicesEditField          matlab.ui.control.NumericEditField
        R2MapLabel                    matlab.ui.control.Label
        M0MapLabel                    matlab.ui.control.Label
        R2MapFig                      matlab.ui.control.UIAxes
        M0MapFig                      matlab.ui.control.UIAxes
        WaterFatTab                   matlab.ui.container.Tab
        WaterImage                    matlab.ui.control.Image
        FatImage                      matlab.ui.control.Image
        FatColorMapButtonGroup        matlab.ui.container.ButtonGroup
        naviaButton5                  matlab.ui.control.RadioButton
        parulaButton5                 matlab.ui.control.RadioButton
        winterButton5                 matlab.ui.control.RadioButton
        autumnButton5                 matlab.ui.control.RadioButton
        grayButton5                   matlab.ui.control.RadioButton
        WaterColorMapButtonGroup      matlab.ui.container.ButtonGroup
        naviaButton4                  matlab.ui.control.RadioButton
        parulaButton4                 matlab.ui.control.RadioButton
        winterButton4                 matlab.ui.control.RadioButton
        autumnButton4                 matlab.ui.control.RadioButton
        grayButton4                   matlab.ui.control.RadioButton
        WaterFatImageSelectionPanel   matlab.ui.container.Panel
        WaterFatMapDynamicsEditFieldLabel  matlab.ui.control.Label
        WaterFatMapSlicesEditFieldLabel  matlab.ui.control.Label
        WaterFatMapDynamicsSlider     matlab.ui.control.Slider
        WaterFatMapDynamicsEditField  matlab.ui.control.NumericEditField
        WaterFatMapSlicesSlider       matlab.ui.control.Slider
        WaterFatMapSlicesEditField    matlab.ui.control.NumericEditField
        FatMapLabel                   matlab.ui.control.Label
        WaterMapLabel                 matlab.ui.control.Label
        FatMapFig                     matlab.ui.control.UIAxes
        WaterMapFig                   matlab.ui.control.UIAxes
        TabGroup1                     matlab.ui.container.TabGroup
        DashboardTab                  matlab.ui.container.Tab
        CSrecoCheckBox                matlab.ui.control.CheckBox
        CSrecoCheckBoxLabel           matlab.ui.control.Label
        MobaCheckBox                  matlab.ui.control.CheckBox
        MobaCheckBoxLabel             matlab.ui.control.Label
        WaterFatCheckBox              matlab.ui.control.CheckBox
        WaterFatCheckBoxLabel         matlab.ui.control.Label
        EstimatedRegTimeViewField     matlab.ui.control.EditField
        RegistrationDropDown          matlab.ui.control.DropDown
        RegAbortButton                matlab.ui.control.Button
        RegProgressGauge              matlab.ui.control.LinearGauge
        RegProgressLabel              matlab.ui.control.Label
        EstimatedFitTimeViewField     matlab.ui.control.EditField
        FitAbortButton                matlab.ui.control.Button
        FitProgressGauge              matlab.ui.control.LinearGauge
        FitProgressLabel              matlab.ui.control.Label
        Lamp7                         matlab.ui.control.Lamp
        Lamp6                         matlab.ui.control.Lamp
        Lamp5                         matlab.ui.control.Lamp
        Lamp4                         matlab.ui.control.Lamp
        Lamp3                         matlab.ui.control.Lamp
        Lamp2                         matlab.ui.control.Lamp
        Lamp1                         matlab.ui.control.Lamp
        ExitButton                    matlab.ui.control.Button
        GIFButton                     matlab.ui.control.Button
        DCMNIFTIButton                matlab.ui.control.Button
        FitT2Button                   matlab.ui.control.Button
        MaskButton                    matlab.ui.control.Button
        RegisterButton                matlab.ui.control.Button
        RecoimagesButton              matlab.ui.control.Button
        LoadMRDfileButton             matlab.ui.control.Button
        MRSImage                      matlab.ui.control.Image
        StatusLabel                   matlab.ui.control.Label
        StatusImage                   matlab.ui.control.Image
        BackgroundBox                 matlab.ui.control.TextArea
        MessageBox                    matlab.ui.control.TextArea
        TulpLogo                      matlab.ui.control.Image
        CreditsLabel                  matlab.ui.control.Label
        T2T2mappingLabel              matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   T2 and T2* mapping tool
    %   
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   July 7, 2025
    %
    %
    %
    %   Version 1.0; aug 2019
    %   - intial version
    %
    %   Version 1.1
    %   - Bug fixes
    %   - Added image registration option
    %
    %   Version 1.2
    %   - Fitting speed dramatically increased
    %
    %   Version 2.0; nov 2020
    %   - updated version
    %
    %   Version 2.1; nov 2020
    %   - T2* mapping of multi-gradient echo sequences
    %   
    %   Version 2.2; nov 2020
    %   - Corrected a bug in image dimensions and non-square aspect ratio
    %
    %   Version 2.3; May 2021
    %   - Multi-coil data
    %
    %   Version 2.4; July 2021
    %   - ROI and cross-hair T2 values
    %
    %   Version 2.6; Jan 2022
    %   - NIFTI ROI import
    %   - Various bug fixes 
    %
    %   Version 3.0
    %   - Model-based reconstruction
    %   - Bug fix in dicom export
    %   - Various bug fixes
    %   - b-type scanner MGE import
    %   - Added dynamics
    %   - 3D T2*
    %   - DICOM export bug fix
    %
    %   Version 3.2
    %   - Able to handle data reconstructed with the P2ROUD app   
    %   - Tested some b-type scanner data
    %   - DICOM export, scaled output x 100 to prevent discretization of low T2(*) values
    %   - bug fixes with DICOM export
    %   - improved GIF, DICOM export
    %
    %   Version 3.3
    %   - Model based fitting on 2D registered images
    %   - ROI drawing in M0 images
    %   - Circular ROI drawing brush
    %   - Elastix image registration
    %
    %   Version 3.5
    %   - Unring, PCA denoising
    %   - Many bug fixes   
    %
    %   Version 3.6
    %   - Added compressed sensing reco parametersd
    %
    %   Version 3.7
    %   - Water fat separation for multi-echo gradient echo
    %   - Updated tooltips
    %   - Mask bug fix
    %
    %   Version 3.9
    %   - Improved version control
    %   - Cleanup some variable names
    %   - GPU bart, if available
    %   - Registration with Bart
    %
    %   Version 4.0
    %   - Some bug fixes, Matlab 2025a version
    %   - Navia color map 
    %
    %
    %
    %
    %
    % --------------------------------------------------------------------------------------
    
    %#ok<*ST2NM> 
    
    
    properties (Access = public)

        % App version
        appVersion = "4.0.6"        % App version
        bartVersion                 % Bart version        
       
        % File variables
        appFolder                   % Application folder
        oldFolder                   % Starting folder
        mrdImportFolder             % Dicom import folder
        dicomExportFolder           % Dicom export folder
        exportFolder                % Dicom/Nifti export folder
        gifExportFolder             % gif export folder
        roiExportFolder             % roi data export folder
        mrdFile                     % mrd data file
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        
        % Flags
        GPUpresentFlag = false      % GPU present true/false
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validRegFlag                % successful registration true/false
        validFitFlag                % valid fit true/false
        validWaterFatFlag           % valid water-fat image generated
        mrdFlag                     % standard mrd import folder already defined true or false
        multiCoilFlag               % multi-coil data
        gradEchoFlag                % gradient echo true/false
        bartDetectedFlag            % bart toolbox available true/false
        data3dFlag                  % 3D data true or false
        p2roudFlag                  % data from proud or retrospective apps
        abortFitFlag                % abort fit false or true
        abortRegFlag                % abort registration false or true
        roiFlag                     % roi drawing true or false
               
        % Scan properties
        dimensions                  % image dimensions
        parameters                  % image parameters
        rprPars                     % rpr parameters
        ne                          % number of echoes
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        nd                          % number of dynamics
        tr                          % repetition time
        te                          % echo time / spacing
        tes                         % all echo times
        esp                         % echo spacing
        orientation                 % phase orientation
        nrCoils                     % number of RF receiver coils
        dataType                    % type of data, e.g. spin-echo or grad-echo
      
        % Images & plots
        images                      % reconstructed abs images (echo, x, y, slice, dynamic)
        complexImages               % reconstructed complex images (echo, x, y, slice, dynamic, coil)
        threshold                   % segmentation threshold (1,ns,nd)
        mask                        % binary image with segmentation (x, y, slice, dynamic)
        norm                        % image normalization factor
        txt1                        % legend label     
        txt2                        % legend label  
        crossT2                     % cross-hair
        crossM0                     % cross-hair
        crossWF                     % cross-hair
        roiPoints                   % roi point coordinates
        roiT2                       % roi drawing object in T2 map
        roiM0                       % roi drawing object in M0 image
        roiWF                       % roi drawing object in water image
        structuringElement2D        % 2D structuring element
        structuringElement3D        % 3D structuring element
        
        % Fitting
        t2map                       % resulting t2map (x, y, slice, dynamic)
        t2cmap                      % T2 color mapping
        m0map                       % resulting m0map (x, y, slice, dynamic)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        watermap                    % Water map
        watercmap                   % Water color map
        fatmap                      % Fat map
        fatcmap                     % Fat color map
        teSelection                 % TE selection array
        roiIntData                  % roi intensities as function of TE
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        lightblue                   % lightblue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end


    methods (Access = private)
        
        
        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX> 
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoimagesButton.Enable = 'off';    else app.RecoimagesButton.Enable = 'on';    end
            if code(3) == 0 app.RegisterButton.Enable = 'off'; app.RegistrationDropDown.Enable = 'off';         else app.RegisterButton.Enable = 'on'; app.RegistrationDropDown.Enable = 'on'; end
            if code(4) == 0 app.MaskButton.Enable = 'off';          else app.MaskButton.Enable = 'on';          end
            if code(5) == 0 app.FitT2Button.Enable = 'off';         else app.FitT2Button.Enable = 'on';         end
            if code(6) == 0 app.DCMNIFTIButton.Enable = 'off';     else app.DCMNIFTIButton.Enable = 'on';       end
            if code(7) == 0 app.GIFButton.Enable = 'off';           else app.GIFButton.Enable = 'on';           end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1}; 
            fontColor = guiColors{2}; 
            % boxColor = guiColors{3}; 
            gaugeColor = guiColors{4}; 
            gaugeBackgroundColor = guiColors{5}; 
            figureColor = guiColors{6}; 
            movieFigureColor = guiColors{7}; 
            % gridColor = guiColors{8}; 
         
            % Panels
            app.T2mappUIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.RawImagesTab.BackgroundColor = backgroundColor;
            app.RecoParametersTab.BackgroundColor = backgroundColor;
            app.FitparametersTab.BackgroundColor = backgroundColor;
            app.AppSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceParametersTab.BackgroundColor = backgroundColor;
            app.T2mapTab.BackgroundColor = backgroundColor;
            app.M0R2Tab.BackgroundColor = backgroundColor;
            app.WaterFatTab.BackgroundColor = backgroundColor;
            app.RawImageSelectionPanel.BackgroundColor = backgroundColor;
            app.RawImageMaskSettingsPanel.BackgroundColor = backgroundColor;
            app.FilteringPanel.BackgroundColor = backgroundColor;
            app.CSregularizationPanel.BackgroundColor = backgroundColor;
            app.SliceSelectionPanel.BackgroundColor = backgroundColor;
            app.DynamicsSelectionPanel.BackgroundColor = backgroundColor;
            app.GoodnessOfFitPanel.BackgroundColor = backgroundColor;
            app.EchoSelectionPanel.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.T2ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.T2ColormapButtonGroup.BackgroundColor = backgroundColor;
            app.M0ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.M0ColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.R2ColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.WaterFatImageSelectionPanel.BackgroundColor = backgroundColor;
            app.WaterColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.FatColorMapButtonGroup.BackgroundColor = backgroundColor;
            app.ColorsPanel.BackgroundColor = backgroundColor;
            app.SoundsPanel.BackgroundColor = backgroundColor;

            % Font colors
            app.CSrecoCheckBoxLabel.FontColor = fontColor;
            app.MobaCheckBoxLabel.FontColor = fontColor;
            app.WaterFatCheckBoxLabel.FontColor = fontColor;
            app.SlicesEditFieldLabel.FontColor = fontColor;
            app.EchoesEditFieldLabel.FontColor = fontColor;
            app.DynamicsEditFieldLabel.FontColor = fontColor;
            app.ThresholdEditFieldLabel.FontColor = fontColor;
            app.WindowEditFieldLabel.FontColor = fontColor;
            app.LevelEditFieldLabel.FontColor = fontColor;
            app.DeNoiseCheckBoxLabel.FontColor = fontColor;
            app.DeRingCheckBoxLabel.FontColor = fontColor;
            app.WVxyzEditFieldLabel.FontColor = fontColor;
            app.TVxyzEditFieldLabel.FontColor = fontColor;
            app.LLRxyzEditFieldLabel.FontColor = fontColor;
            app.TVdynEditFieldLabel.FontColor = fontColor;
            app.TVteEditFieldLabel.FontColor = fontColor;
            app.FirstSliceEditFieldLabel.FontColor = fontColor;
            app.LastSliceEditFieldLabel.FontColor = fontColor;
            app.FirstDynamicEditFieldLabel.FontColor = fontColor;
            app.LastDynamicEditFieldLabel.FontColor = fontColor;
            app.RsquareEditFieldLabel.FontColor = fontColor;
            app.CheckBox_1.FontColor = fontColor;    app.CheckBox_17.FontColor = fontColor;   app.CheckBox_33.FontColor = fontColor;   app.CheckBox_49.FontColor = fontColor;
            app.CheckBox_2.FontColor = fontColor;    app.CheckBox_18.FontColor = fontColor;   app.CheckBox_34.FontColor = fontColor;   app.CheckBox_50.FontColor = fontColor;
            app.CheckBox_3.FontColor = fontColor;    app.CheckBox_19.FontColor = fontColor;   app.CheckBox_35.FontColor = fontColor;   app.CheckBox_51.FontColor = fontColor;
            app.CheckBox_4.FontColor = fontColor;    app.CheckBox_20.FontColor = fontColor;   app.CheckBox_36.FontColor = fontColor;   app.CheckBox_52.FontColor = fontColor;
            app.CheckBox_5.FontColor = fontColor;    app.CheckBox_21.FontColor = fontColor;   app.CheckBox_37.FontColor = fontColor;   app.CheckBox_53.FontColor = fontColor;
            app.CheckBox_6.FontColor = fontColor;    app.CheckBox_22.FontColor = fontColor;   app.CheckBox_38.FontColor = fontColor;   app.CheckBox_54.FontColor = fontColor;
            app.CheckBox_7.FontColor = fontColor;    app.CheckBox_23.FontColor = fontColor;   app.CheckBox_39.FontColor = fontColor;   app.CheckBox_55.FontColor = fontColor;
            app.CheckBox_8.FontColor = fontColor;    app.CheckBox_24.FontColor = fontColor;   app.CheckBox_40.FontColor = fontColor;   app.CheckBox_56.FontColor = fontColor;
            app.CheckBox_9.FontColor = fontColor;    app.CheckBox_25.FontColor = fontColor;   app.CheckBox_41.FontColor = fontColor;   app.CheckBox_57.FontColor = fontColor;
            app.CheckBox_10.FontColor = fontColor;   app.CheckBox_26.FontColor = fontColor;   app.CheckBox_42.FontColor = fontColor;   app.CheckBox_58.FontColor = fontColor;
            app.CheckBox_11.FontColor = fontColor;   app.CheckBox_27.FontColor = fontColor;   app.CheckBox_43.FontColor = fontColor;   app.CheckBox_59.FontColor = fontColor;
            app.CheckBox_12.FontColor = fontColor;   app.CheckBox_28.FontColor = fontColor;   app.CheckBox_44.FontColor = fontColor;   app.CheckBox_60.FontColor = fontColor;
            app.CheckBox_13.FontColor = fontColor;   app.CheckBox_29.FontColor = fontColor;   app.CheckBox_45.FontColor = fontColor;   app.CheckBox_61.FontColor = fontColor;
            app.CheckBox_14.FontColor = fontColor;   app.CheckBox_30.FontColor = fontColor;   app.CheckBox_46.FontColor = fontColor;   app.CheckBox_62.FontColor = fontColor;
            app.CheckBox_15.FontColor = fontColor;   app.CheckBox_31.FontColor = fontColor;   app.CheckBox_47.FontColor = fontColor;   app.CheckBox_63.FontColor = fontColor;
            app.CheckBox_16.FontColor = fontColor;   app.CheckBox_32.FontColor = fontColor;   app.CheckBox_48.FontColor = fontColor;   app.CheckBox_64.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.DataFileViewFieldLabel.FontColor = fontColor;
            app.TRViewFieldLabel.FontColor = fontColor;
            app.TE0EditFieldLabel.FontColor = fontColor;
            app.EchoesViewFieldLabel.FontColor = fontColor;
            app.DynamicsViewFieldLabel.FontColor = fontColor;
            app.ESPEditFieldLabel.FontColor = fontColor;
            app.AspectRatioViewFieldLabel.FontColor = fontColor;
            app.FOVViewFieldLabel.FontColor = fontColor;
            app.DimxViewFieldLabel.FontColor = fontColor;
            app.DimyViewFieldLabel.FontColor = fontColor;
            app.SlicesViewFieldLabel.FontColor = fontColor;
            app.SLTViewFieldLabel.FontColor = fontColor;
            app.T2MapSlicesEditFieldLabel.FontColor = fontColor;
            app.T2MapDynamicsEditFieldLabel.FontColor = fontColor;
            app.T2ScaleMaxLabel.FontColor = fontColor;
            app.T2BrushEditFieldLabel.FontColor = fontColor;
            app.grayButton1.FontColor = fontColor;
            app.autumnButton1.FontColor = fontColor;
            app.winterButton1.FontColor = fontColor;
            app.parulaButton1.FontColor = fontColor;
            app.naviaButton1.FontColor = fontColor;
            app.T2XEditFieldLabel.FontColor = fontColor;
            app.T2YEditFieldLabel.FontColor = fontColor;
            app.T2ViewFieldLabel.FontColor = fontColor;
            app.T2PixelValueLabel.FontColor = fontColor;
            app.T2ROIViewFieldLabel.FontColor = fontColor;
            app.M0MapSlicesEditFieldLabel.FontColor = fontColor;
            app.M0MapDynamicsEditFieldLabel.FontColor = fontColor;
            app.grayButton2.FontColor = fontColor;
            app.autumnButton2.FontColor = fontColor;
            app.winterButton2.FontColor = fontColor;
            app.parulaButton2.FontColor = fontColor;
            app.naviaButton2.FontColor = fontColor;
            app.M0BrushEditFieldLabel.FontColor = fontColor;
            app.grayButton3.FontColor = fontColor;
            app.autumnButton3.FontColor = fontColor;
            app.winterButton3.FontColor = fontColor;
            app.parulaButton3.FontColor = fontColor;
            app.naviaButton3.FontColor = fontColor;
            app.WaterFatMapSlicesEditFieldLabel.FontColor = fontColor;
            app.WaterFatMapDynamicsEditFieldLabel.FontColor = fontColor;
            app.grayButton4.FontColor = fontColor;
            app.autumnButton4.FontColor = fontColor;
            app.winterButton4.FontColor = fontColor;
            app.parulaButton4.FontColor = fontColor;
            app.naviaButton4.FontColor = fontColor;
            app.grayButton5.FontColor = fontColor;
            app.autumnButton5.FontColor = fontColor;
            app.winterButton5.FontColor = fontColor;
            app.parulaButton5.FontColor = fontColor;
            app.naviaButton5.FontColor = fontColor;

            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Images
            app.RawImageLabel.FontColor = fontColor;
            app.T2MapLabel.FontColor = fontColor;
            app.M0MapLabel.FontColor = fontColor;
            app.R2MapLabel.FontColor = fontColor;
            app.WaterMapLabel.FontColor = fontColor;
            app.FatMapLabel.FontColor = fontColor;
            app.LogLabel.FontColor = fontColor;
            app.LinearLabel.FontColor = fontColor;

            app.RawDataFig.Color = movieFigureColor;
            app.RawDataFig.XColor = movieFigureColor;
            app.RawDataFig.YColor = movieFigureColor;

            app.T2MapFig.Color = movieFigureColor;
            app.T2MapFig.XColor = movieFigureColor;
            app.T2MapFig.YColor = movieFigureColor;

            app.T2logPlot.Color = figureColor;
            app.T2logPlot.XColor = fontColor;
            app.T2logPlot.YColor = fontColor;
         
            app.T2linearPlot.Color = figureColor;
            app.T2linearPlot.XColor = fontColor;
            app.T2linearPlot.YColor = fontColor;

            app.M0MapFig.Color = movieFigureColor;
            app.M0MapFig.XColor = movieFigureColor;
            app.M0MapFig.YColor = movieFigureColor;

            app.R2MapFig.Color = movieFigureColor;
            app.R2MapFig.XColor = movieFigureColor;
            app.R2MapFig.YColor = movieFigureColor;

            app.WaterMapFig.Color = movieFigureColor;
            app.WaterMapFig.XColor = movieFigureColor;
            app.WaterMapFig.YColor = movieFigureColor;

            app.FatMapFig.Color = movieFigureColor;
            app.FatMapFig.XColor = movieFigureColor;
            app.FatMapFig.YColor = movieFigureColor;

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            try
                currentVersion = string(app.appVersion);
                newestVersions = {"4.5","4.4","4.3","4.2","4.1","4.0","3.9","3.8","3.7","3.6","3.5","3.4","3.3","3.2","3.1","3.0"};

                webTxt = "";
                ver = 0;

                opts = weboptions( ...
                    'Timeout', 5, ...
                    'ContentType', 'text', ...
                    'CharacterEncoding', 'UTF-8');

                while webTxt == "" && ver < numel(newestVersions)
                    ver = ver + 1;

                    newVersionFile = "https://raw.githubusercontent.com/Moby1971/T2mapping-v" + ...
                        string(newestVersions{ver}) + "/master/appversion.txt";

                    try
                        webTxt = strtrim(string(webread(char(newVersionFile), opts)));
                    catch
                        webTxt = "";
                    end
                end

                if webTxt == ""
                    ME = MException('T2mapping:error','Could not retrieve version file ...');
                    throw(ME);
                end

                tok = regexp(webTxt, 't2mapp_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once', 'ignorecase');
                if isempty(tok)
                    ME = MException('T2mapping:error','Could not parse version string from file ...');
                    throw(ME);
                end

                newVersion = string(tok{1});

                app.TextMessage("Current version = " + currentVersion);
                app.TextMessage("Newest version = " + newVersion);

                if isVersionNewer(newVersion, currentVersion)
                    app.TextMessage("A new version of the T2 mapping app is available ...");
                    app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
                else
                    app.TextMessage("The T2 mapping app is up to date ...");
                end

            catch ME
                disp(ME.message)
                app.TextMessage("Could not check T2 mapping app version ...");
            end

            function tf = isVersionNewer(newVer, curVer)
                a = sscanf(char(newVer), '%d.%d.%d').';
                b = sscanf(char(curVer), '%d.%d.%d').';
                a(end+1:3) = 0;
                b(end+1:3) = 0;
                tf = false;
                for k = 1:3
                    if a(k) > b(k)
                        tf = true;
                        return
                    elseif a(k) < b(k)
                        return
                    end
                end
            end

        end % CheckVersionFnc
        
        
        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.FirstSliceEditFieldLabel.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.LastSliceEditFieldLabel.Enable = 'off';
                app.FirstDynamicEditField.Enable = 'off';
                app.FirstDynamicEditFieldLabel.Enable = 'off';
                app.LastDynamicEditField.Enable = 'off';
                app.LastDynamicEditFieldLabel.Enable = 'off';
                app.RsquareEditField.Enable = 'off';
                app.RsquareEditFieldLabel.Enable = 'off';
                app.FitAbortButton.Enable = 'off';
                app.CSrecoCheckBox.Enable = 'off';
                app.CSrecoCheckBoxLabel.Enable = 'off';
                app.WaterFatCheckBox.Enable = 'off';
                app.WaterFatCheckBoxLabel.Enable = 'off';

                app.DeNoiseCheckBox.Enable = 'off';
                app.DeNoiseCheckBoxLabel.Enable = 'off';
                app.DeNoiseWindowEditField.Enable = 'off';
                app.DeRingCheckBox.Enable = 'off';
                app.DeRingCheckBoxLabel.Enable = 'off';
              
                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVteEditField.Enable = 'off';
                app.TVteEditFieldLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';
                
                app.MobaCheckBox.Enable = 'off';
                app.MobaCheckBoxLabel.Enable = 'off';
                app.RegistrationDropDown.Enable = 'off';
                
                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';

            else
            
                if app.ns > 1
                    app.FirstSliceEditField.Enable = 'on';
                    app.FirstSliceEditFieldLabel.Enable = 'on';
                    app.LastSliceEditField.Enable = 'on';
                    app.LastSliceEditFieldLabel.Enable = 'on';
                else
                    app.FirstSliceEditField.Enable = 'off';
                    app.FirstSliceEditFieldLabel.Enable = 'off';
                    app.LastSliceEditField.Enable = 'off';
                    app.LastSliceEditFieldLabel.Enable = 'off';
                end

                if app.nd > 1
                    app.FirstDynamicEditField.Enable = 'on';
                    app.FirstDynamicEditFieldLabel.Enable = 'on';
                    app.LastDynamicEditField.Enable = 'on';
                    app.LastDynamicEditFieldLabel.Enable = 'on';
                else
                    app.FirstDynamicEditField.Enable = 'off';
                    app.FirstDynamicEditFieldLabel.Enable = 'off';
                    app.LastDynamicEditField.Enable = 'off';
                    app.LastDynamicEditFieldLabel.Enable = 'off';
                end

                app.RsquareEditField.Enable = 'on';
                app.RsquareEditFieldLabel.Enable = 'on';
         
                if app.bartDetectedFlag && app.validDataFlag
                    app.CSrecoCheckBox.Enable = 'on';
                    app.CSrecoCheckBoxLabel.Enable = 'on';
                else
                    app.CSrecoCheckBox.Enable = 'off';
                    app.CSrecoCheckBoxLabel.Enable = 'off';
                end

                app.DeNoiseCheckBox.Enable = 'on';
                app.DeNoiseCheckBoxLabel.Enable = 'on';
                app.DeNoiseWindowEditField.Enable = 'on';
                app.DeRingCheckBox.Enable = 'on';
                app.DeRingCheckBoxLabel.Enable = 'on';
                
                app.WVxyzEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVteEditField.Enable = 'on';
                app.TVteEditFieldLabel.Enable = 'on';
                app.TVdynEditField.Enable = 'on';
                app.TVdynEditFieldLabel.Enable = 'on';
              
                if app.validRecoFlag
                    app.RegistrationDropDown.Enable = 'on';
                else
                    app.RegistrationDropDown.Enable = 'off';
                end

                if app.bartDetectedFlag && app.validRecoFlag
                    app.MobaCheckBox.Enable = 'on';
                    app.MobaCheckBoxLabel.Enable = 'on';
                else
                    app.MobaCheckBox.Enable = 'off';
                    app.MobaCheckBoxLabel.Enable = 'off';
                end

                if app.validRecoFlag && app.gradEchoFlag
                    app.WaterFatCheckBox.Enable = 'on';
                    app.WaterFatCheckBoxLabel.Enable = 'on';
                else
                    app.WaterFatCheckBox.Enable = 'off';
                    app.WaterFatCheckBoxLabel.Enable = 'off';

                end

                app.CheckBox_1.Enable = 'on';
                if app.ne > 1 app.CheckBox_2.Enable = 'on'; end;        if app.ne > 32 app.CheckBox_33.Enable = 'on'; end
                if app.ne > 2 app.CheckBox_3.Enable = 'on'; end;        if app.ne > 33 app.CheckBox_34.Enable = 'on'; end
                if app.ne > 3 app.CheckBox_4.Enable = 'on'; end;        if app.ne > 34 app.CheckBox_35.Enable = 'on'; end
                if app.ne > 4 app.CheckBox_5.Enable = 'on'; end;        if app.ne > 35 app.CheckBox_36.Enable = 'on'; end
                if app.ne > 5 app.CheckBox_6.Enable = 'on'; end;        if app.ne > 36 app.CheckBox_37.Enable = 'on'; end
                if app.ne > 6 app.CheckBox_7.Enable = 'on'; end;        if app.ne > 37 app.CheckBox_38.Enable = 'on'; end
                if app.ne > 7 app.CheckBox_8.Enable = 'on'; end;        if app.ne > 38 app.CheckBox_39.Enable = 'on'; end
                if app.ne > 8 app.CheckBox_9.Enable = 'on'; end;        if app.ne > 39 app.CheckBox_40.Enable = 'on'; end
                if app.ne > 9 app.CheckBox_10.Enable = 'on'; end;       if app.ne > 40 app.CheckBox_41.Enable = 'on'; end
                if app.ne > 10 app.CheckBox_11.Enable = 'on'; end;      if app.ne > 41 app.CheckBox_42.Enable = 'on'; end
                if app.ne > 11 app.CheckBox_12.Enable = 'on'; end;      if app.ne > 42 app.CheckBox_43.Enable = 'on'; end
                if app.ne > 12 app.CheckBox_13.Enable = 'on'; end;      if app.ne > 43 app.CheckBox_44.Enable = 'on'; end
                if app.ne > 13 app.CheckBox_14.Enable = 'on'; end;      if app.ne > 44 app.CheckBox_45.Enable = 'on'; end
                if app.ne > 14 app.CheckBox_15.Enable = 'on'; end;      if app.ne > 45 app.CheckBox_46.Enable = 'on'; end
                if app.ne > 15 app.CheckBox_16.Enable = 'on'; end;      if app.ne > 46 app.CheckBox_47.Enable = 'on'; end
                if app.ne > 16 app.CheckBox_17.Enable = 'on'; end;      if app.ne > 47 app.CheckBox_48.Enable = 'on'; end
                if app.ne > 17 app.CheckBox_18.Enable = 'on'; end;      if app.ne > 48 app.CheckBox_49.Enable = 'on'; end
                if app.ne > 18 app.CheckBox_19.Enable = 'on'; end;      if app.ne > 49 app.CheckBox_50.Enable = 'on'; end
                if app.ne > 19 app.CheckBox_20.Enable = 'on'; end;      if app.ne > 50 app.CheckBox_51.Enable = 'on'; end
                if app.ne > 20 app.CheckBox_21.Enable = 'on'; end;      if app.ne > 51 app.CheckBox_52.Enable = 'on'; end
                if app.ne > 21 app.CheckBox_22.Enable = 'on'; end;      if app.ne > 52 app.CheckBox_53.Enable = 'on'; end
                if app.ne > 22 app.CheckBox_23.Enable = 'on'; end;      if app.ne > 53 app.CheckBox_54.Enable = 'on'; end
                if app.ne > 23 app.CheckBox_24.Enable = 'on'; end;      if app.ne > 54 app.CheckBox_55.Enable = 'on'; end
                if app.ne > 24 app.CheckBox_25.Enable = 'on'; end;      if app.ne > 55 app.CheckBox_56.Enable = 'on'; end
                if app.ne > 25 app.CheckBox_26.Enable = 'on'; end;      if app.ne > 56 app.CheckBox_57.Enable = 'on'; end
                if app.ne > 26 app.CheckBox_27.Enable = 'on'; end;      if app.ne > 57 app.CheckBox_58.Enable = 'on'; end
                if app.ne > 27 app.CheckBox_28.Enable = 'on'; end;      if app.ne > 58 app.CheckBox_59.Enable = 'on'; end
                if app.ne > 28 app.CheckBox_29.Enable = 'on'; end;      if app.ne > 59 app.CheckBox_60.Enable = 'on'; end
                if app.ne > 29 app.CheckBox_30.Enable = 'on'; end;      if app.ne > 60 app.CheckBox_61.Enable = 'on'; end
                if app.ne > 30 app.CheckBox_31.Enable = 'on'; end;      if app.ne > 61 app.CheckBox_62.Enable = 'on'; end
                if app.ne > 31 app.CheckBox_32.Enable = 'on'; end;      if app.ne > 62 app.CheckBox_63.Enable = 'on'; end
                if app.ne > 63 app.CheckBox_64.Enable = 'on'; end
                
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0

                app.SlicesEditField.Enable = 'off';
                app.SlicesEditFieldLabel.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.EchoesEditField.Enable = 'off';
                app.EchoesEditFieldLabel.Enable = 'off';
                app.EchoesSlider.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.DynamicsEditFieldLabel.Enable = 'off';
                app.DynamicsSlider.Enable = 'off';
                app.WindowEditField.Enable = 'off';
                app.WindowEditFieldLabel.Enable = 'off';
                app.WindowSlider.Enable = 'off';
                app.LevelEditField.Enable = 'off';
                app.LevelEditFieldLabel.Enable = 'off';
                app.LevelSlider.Enable = 'off';
                app.AutoLWButton.Enable = 'off';

            else

                if app.ns >1
                    app.SlicesEditField.Enable = 'on';
                    app.SlicesEditFieldLabel.Enable = 'on';
                    app.SlicesSlider.Enable = 'on';
                else
                    app.SlicesEditField.Enable = 'off';
                    app.SlicesEditFieldLabel.Enable = 'off';
                    app.SlicesSlider.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField.Enable = 'on';
                    app.DynamicsEditFieldLabel.Enable = 'on';
                    app.DynamicsSlider.Enable = 'on';
                else
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsEditFieldLabel.Enable = 'off';
                    app.DynamicsSlider.Enable = 'off';
                end
                app.EchoesEditField.Enable = 'on';
                app.EchoesEditFieldLabel.Enable = 'on';
                app.EchoesSlider.Enable = 'on';
                app.WindowEditField.Enable = 'on';
                app.WindowEditFieldLabel.Enable = 'on';
                app.WindowSlider.Enable = 'on';
                app.LevelEditField.Enable = 'on';
                app.LevelEditFieldLabel.Enable = 'on';
                app.LevelSlider.Enable = 'on';
                app.AutoLWButton.Enable = 'on';

            end
            
            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdEditFieldLabel.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdEditFieldLabel.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch T2 map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0

                app.T2MapSlicesEditField.Enable = 'off';
                app.T2MapSlicesEditFieldLabel.Enable = 'off';
                app.M0MapSlicesEditField.Enable = 'off';
                app.M0MapSlicesEditFieldLabel.Enable = 'off';
                app.T2MapSlicesSlider.Enable = 'off';
                app.M0MapSlicesSlider.Enable = 'off';
                app.T2MapDynamicsEditField.Enable = 'off';
                app.T2MapDynamicsEditFieldLabel.Enable = 'off';
                app.M0MapDynamicsEditField.Enable = 'off';
                app.M0MapDynamicsEditFieldLabel.Enable = 'off';
                app.T2MapDynamicsSlider.Enable = 'off';
                app.M0MapDynamicsSlider.Enable = 'off';
                app.WaterFatMapSlicesEditField.Enable = 'off';
                app.WaterFatMapSlicesEditFieldLabel.Enable = 'off';
                app.WaterFatMapSlicesSlider.Enable = 'off';
                app.WaterFatMapDynamicsEditField.Enable = 'off';
                app.WaterFatMapDynamicsEditFieldLabel.Enable = 'off';
                app.WaterFatMapDynamicsSlider.Enable = 'off';
                app.T2ScaleEditField.Enable = 'off';
                app.T2ScaleMaxLabel.Enable = 'off';
                app.T2ScaleAutoButton.Enable = 'off';
                app.grayButton1.Enable = 'off';
                app.autumnButton1.Enable = 'off';
                app.winterButton1.Enable = 'off';
                app.parulaButton1.Enable = 'off';
                app.naviaButton1.Enable = 'off';
                app.grayButton2.Enable = 'off';
                app.autumnButton2.Enable = 'off';
                app.winterButton2.Enable = 'off';
                app.parulaButton2.Enable = 'off';
                app.naviaButton2.Enable = 'off';
                app.grayButton3.Enable = 'off';
                app.autumnButton3.Enable = 'off';
                app.winterButton3.Enable = 'off';
                app.parulaButton3.Enable = 'off';
                app.naviaButton3.Enable = 'off';
                app.grayButton4.Enable = 'off';
                app.autumnButton4.Enable = 'off';
                app.winterButton4.Enable = 'off';
                app.parulaButton4.Enable = 'off';
                app.naviaButton4.Enable = 'off';
                app.grayButton5.Enable = 'off';
                app.autumnButton5.Enable = 'off';
                app.winterButton5.Enable = 'off';
                app.parulaButton5.Enable = 'off';
                app.naviaButton5.Enable = 'off';
                app.FitAbortButton.Enable = 'off';
                app.T2ROIbutton.Enable = 'off';
                app.T2ROIEraseButton.Enable = 'off';
                app.T2BrushEditField.Enable = 'off';
                app.T2BrushEditFieldLabel.Enable = 'off';
                app.T2ExportRoiDataButton.Enable = 'off';
                app.M0ROIButton.Enable = 'off';
                app.M0ROIEraseButton.Enable = 'off';
                app.M0BrushEditField.Enable = 'off';
                app.M0BrushEditFieldLabel.Enable = 'off';

            else

                if app.ns > 1
                    app.T2MapSlicesEditField.Enable = 'on';
                    app.T2MapSlicesEditFieldLabel.Enable = 'on';
                    app.T2MapSlicesSlider.Enable = 'on';
                    app.M0MapSlicesEditField.Enable = 'on';
                    app.M0MapSlicesEditFieldLabel.Enable = 'on';
                    app.M0MapSlicesSlider.Enable = 'on';
                    app.WaterFatMapSlicesEditField.Enable = 'on';
                    app.WaterFatMapSlicesEditFieldLabel.Enable = 'on';
                    app.WaterFatMapSlicesSlider.Enable = 'on';
                else
                    app.T2MapSlicesEditField.Enable = 'off';
                    app.T2MapSlicesEditFieldLabel.Enable = 'off';
                    app.T2MapSlicesSlider.Enable = 'off';
                    app.M0MapSlicesEditField.Enable = 'off';
                    app.M0MapSlicesEditFieldLabel.Enable = 'off';
                    app.M0MapSlicesSlider.Enable = 'off';
                    app.WaterFatMapSlicesEditField.Enable = 'off';
                    app.WaterFatMapSlicesEditFieldLabel.Enable = 'off';
                    app.WaterFatMapSlicesSlider.Enable = 'off';
                end
                if app.nd > 1
                    app.T2MapDynamicsEditField.Enable = 'on';
                    app.T2MapDynamicsEditFieldLabel.Enable = 'on';
                    app.T2MapDynamicsSlider.Enable = 'on';
                    app.M0MapDynamicsEditField.Enable = 'on';
                    app.M0MapDynamicsEditFieldLabel.Enable = 'on';
                    app.M0MapDynamicsSlider.Enable = 'on';
                    app.WaterFatMapDynamicsEditField.Enable = 'on';
                    app.WaterFatMapDynamicsEditFieldLabel.Enable = 'on';
                    app.WaterFatMapDynamicsSlider.Enable = 'on';
                else
                    app.T2MapDynamicsEditField.Enable = 'off';
                    app.T2MapDynamicsEditFieldLabel.Enable = 'off';
                    app.T2MapDynamicsSlider.Enable = 'off';
                    app.M0MapDynamicsEditField.Enable = 'off';
                    app.M0MapDynamicsEditFieldLabel.Enable = 'off';
                    app.M0MapDynamicsSlider.Enable = 'off';
                    app.WaterFatMapDynamicsEditField.Enable = 'off';
                    app.WaterFatMapDynamicsEditFieldLabel.Enable = 'off';
                    app.WaterFatMapDynamicsSlider.Enable = 'off';
                end
                app.T2ScaleEditField.Enable = 'on';
                app.T2ScaleMaxLabel.Enable = 'on';
                app.T2ScaleAutoButton.Enable = 'on';
                app.grayButton1.Enable = 'on';
                app.autumnButton1.Enable = 'on';
                app.winterButton1.Enable = 'on';
                app.parulaButton1.Enable = 'on';
                app.naviaButton1.Enable = 'on';
                app.grayButton2.Enable = 'on';
                app.autumnButton2.Enable = 'on';
                app.winterButton2.Enable = 'on';
                app.parulaButton2.Enable = 'on';
                app.naviaButton2.Enable = 'on';
                app.grayButton3.Enable = 'on';
                app.autumnButton3.Enable = 'on';
                app.winterButton3.Enable = 'on';
                app.parulaButton3.Enable = 'on';
                app.naviaButton3.Enable = 'on';
                app.grayButton4.Enable = 'on';
                app.autumnButton4.Enable = 'on';
                app.winterButton4.Enable = 'on';
                app.parulaButton4.Enable = 'on';
                app.naviaButton4.Enable = 'on';
                app.grayButton5.Enable = 'on';
                app.autumnButton5.Enable = 'on';
                app.winterButton5.Enable = 'on';
                app.parulaButton5.Enable = 'on';
                app.naviaButton5.Enable = 'on';
                app.T2ROIbutton.Enable = 'on';
                app.T2ROIEraseButton.Enable = 'on';
                app.T2BrushEditField.Enable = 'on';
                app.T2BrushEditFieldLabel.Enable = 'on';
                app.T2ExportRoiDataButton.Enable = 'on';
                app.M0ROIButton.Enable = 'on';
                app.M0ROIEraseButton.Enable = 'on';
                app.M0BrushEditField.Enable = 'on';
                app.M0BrushEditFieldLabel.Enable = 'on';

            end
           
            drawnow;
            
        end


        % Change the slice value
        function ChangeMapSlices(app, value)

            app.T2MapSlicesSlider.Value = round(value);
            app.M0MapSlicesSlider.Value = round(value);
            app.WaterFatMapSlicesSlider.Value = round(value);

            app.T2MapSlicesEditField.Value = round(value);
            app.M0MapSlicesEditField.Value = round(value);
            app.WaterFatMapSlicesEditField.Value = round(value);

        end


        % Change the dynamics value
        function ChangeMapDynamics(app, value)

            app.T2MapDynamicsSlider.Value = round(value);
            app.M0MapDynamicsSlider.Value = round(value);
            app.WaterFatMapDynamicsSlider.Value = round(value);

            app.T2MapDynamicsEditField.Value = round(value);
            app.M0MapDynamicsEditField.Value = round(value);
            app.WaterFatMapDynamicsEditField.Value = round(value);

        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');
            
            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ", app.appVersion);
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.9,0.9,0.9];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

             % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoimagesButton.Icon = 'reconstruct.gif';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitT2Button.Icon = 'exponential.gif';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T2Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            app.WaterImage.ImageSource = 'square.gif';
            app.FatImage.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T2MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T2logPlot.Toolbar.Visible = 'off';
            app.T2linearPlot.Toolbar.Visible = 'off';
            app.t2cmap = naviaColorMap();
            app.m0cmap = gray;
            app.r2cmap = naviaColorMap();
            app.watercmap = gray;
            app.fatcmap = gray;
            app.t2cmap(1,:) = [0 0 0];
            app.m0cmap(1,:) = [0 0 0];
            app.r2cmap(1,:) = [0 0 0];
            app.watercmap(1,:) = [0 0 0];
            app.fatcmap(1,:) = [0 0 0];
            app.naviaButton1.Value = 1;
            app.grayButton2.Value = 1;
            app.naviaButton3.Value = 1;
            app.grayButton4.Value = 1;
            app.grayButton5.Value = 1;

            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T2MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T2logPlot.Interactions = [];
            app.T2linearPlot.Interactions = [];
            
            % ROis and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);

            % CS RECO
            app.WVxyzEditField.Value = 0.01;
            app.TVxyzEditField.Value = 0;
            app.LLRxyzEditField.Value = 0.005;
            app.TVteEditField.Value = 0;
            app.TVdynEditField.Value = 0.001;
            app.DeNoiseCheckBox.Value = 1;

            app.SetStatus(3);
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)
          
            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');
            
            % Define the colormap
            % Since it is not possible to have 2 colormaps in 1 figure, the
            % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;
                        
            % Background anatomy image
            im = squeeze(app.images(app.EchoesEditField.Value,:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.parameters.PHASE_ORIENTATION
                im = rot90(im,-1);
            end

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and Level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            % Plot the background image
            xlim(app.RawDataFig,[0, size(im,1) + 1]);
            ylim(app.RawDataFig,[0, size(im,2) + 1]);
            background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig); %#ok<NASGU> 
            
            % Overlay image with the mask
            imo = squeeze(2-app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            alpha = squeeze(0.7-0.7*app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.parameters.PHASE_ORIENTATION
                imo = rot90(imo,-1);
                alpha = rot90(alpha,-1);
            end
            overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig);
            set(overlay,'AlphaData',rot90(alpha));

            % Image aspect ratio
            if app.parameters.PHASE_ORIENTATION
                aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                daspect(app.RawDataFig,[1 aratio 1]);
            else
                aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                daspect(app.RawDataFig,[aratio 1 1]);
            end
            

            hold(app.RawDataFig,'off');
            drawnow;
            
            % Set some limits now
            maxim = app.images(1,:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value);
            app.ThresholdEditField.Value = 0;
            app.ThresholdSlider.Value = 0;
            app.ThresholdEditField.Limits = [0 max(maxim(:))+1];
            app.ThresholdSlider.Limits = [0 max(maxim(:))+1];
            thr = app.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);
            thr(thr<0)=0;
            thr(thr>max(maxim(:)))= max(maxim(:));
            app.ThresholdEditField.Value = round(thr);
            app.ThresholdSlider.Value = thr;
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.T2MapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');
                hold(app.WaterMapFig,'on');
                hold(app.FatMapFig,'on');

                % Plot the T2 map
                im = squeeze(app.t2map(:,:,app.T2MapSlicesEditField.Value,app.T2MapDynamicsEditField.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.T2MapFig,[0, size(im,1) + 1]);
                ylim(app.T2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 app.T2ScaleEditField.Value],'Colormap',app.t2cmap,'InitialMagnification','fit','Parent', app.T2MapFig);
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                    daspect(app.T2MapFig,[1 aratio 1]);
                else
                    aratio = app.AspectRatioViewField.Value*size(im,1)/size(im,2);
                    daspect(app.T2MapFig,[aratio 1 1]);
                end
                colorbar(app.T2MapFig,'east','Ticks',[]);

                % Plot the M0 map
                im = squeeze(app.m0map(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                maxIm = max(im(:));
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.M0MapFig,[0, size(im,1) + 1]);
                ylim(app.M0MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent', app.M0MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.M0MapFig,[1 aratio 1]);
                else
                    daspect(app.M0MapFig,[aratio 1 1]);
                end
              
                % Plot the R2 map
                im = squeeze(app.r2map(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.R2MapFig,[0, size(im,1) + 1]);
                ylim(app.R2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.RsquareEditField.Value-0.01 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent', app.R2MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.R2MapFig,[1 aratio 1]);
                else
                    daspect(app.R2MapFig,[aratio 1 1]);
                end
                colorbar(app.R2MapFig,'east','Ticks',[app.RsquareEditField.Value-0.01, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.85,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.85,0.05,num2str(app.RsquareEditField.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                % Plot the water image
                im = squeeze(app.watermap(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                maxIm = 100;
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.WaterMapFig,[0, size(im,1) + 1]);
                ylim(app.WaterMapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.watercmap,'InitialMagnification','fit','Parent', app.WaterMapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.WaterMapFig,[1 aratio 1]);
                else
                    daspect(app.WaterMapFig,[aratio 1 1]);
                end
                colorbar(app.WaterMapFig,'east','Ticks',[0, 100]);
                text(app.WaterMapFig,0.82,0.95,'100','Units','normalized','Color',app.grey,'FontSize',14);
                text(app.WaterMapFig,0.85,0.05,'0','Units','normalized','Color',app.grey,'FontSize',14);
                
                % Plot the fat image
                im = squeeze(app.fatmap(:,:,app.T2MapSlicesEditField.Value,app.M0MapDynamicsEditField.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                maxIm = 100;
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;
                xlim(app.FatMapFig,[0, size(im,1) + 1]);
                ylim(app.FatMapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.fatcmap,'InitialMagnification','fit','Parent', app.FatMapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.FatMapFig,[1 aratio 1]);
                else
                    daspect(app.FatMapFig,[aratio 1 1]);
                end
                colorbar(app.FatMapFig,'east','Ticks',[0, 100]);
                text(app.FatMapFig,0.82,0.95,'100','Units','normalized','Color',app.grey,'FontSize',14);
                text(app.FatMapFig,0.85,0.05,'0','Units','normalized','Color',app.grey,'FontSize',14);
            
                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.T2MapSlicesEditField.Value, :);
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx;
                    else
                        ysize = app.dimy;
                    end
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);
                end

                hold(app.T2MapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                hold(app.WaterMapFig,'off');
                hold(app.FatMapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Draw a cross-hair on the T2 map
        function PlotCross(app,xcor,ycor,ysize)

            delete(app.crossT2);
            delete(app.crossM0);
            delete(app.crossWF);

            clr1 = 'r';
            if app.autumnButton1.Value == 1 || app.naviaButton1.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.naviaButton2.Value == 1
                clr2 = 'g';
            end

            clr3 = 'r';
            if app.autumnButton4.Value == 1 || app.naviaButton4.Value == 1
                clr3 = 'g';
            end

            app.crossT2 = drawcrosshair(app.T2MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
            app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);
            app.crossWF = drawcrosshair(app.WaterMapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr3, 'LineWidth', 1);

        end % PlotCross


        % Draw points on the T2 map
        function PlotPoint(app,xcor,ycor,ysize)

            clr1 = 'r';
            if app.autumnButton1.Value == 1 || app.naviaButton1.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.naviaButton2.Value == 1
                clr2 = 'g';
            end

            clr3 = 'r';
            if app.autumnButton4.Value == 1 || app.naviaButton4.Value == 1
                clr3 = 'g';
            end

            hold(app.T2MapFig,'on');
            delete(app.roiT2);
            app.roiT2 = plot(app.T2MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
            hold(app.T2MapFig,'off');

            hold(app.M0MapFig,'on');
            delete(app.roiM0);
            app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
            hold(app.M0MapFig,'off');

            hold(app.WaterMapFig,'on');
            delete(app.roiWF);
            app.roiWF = plot(app.WaterMapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr3);
            hold(app.WaterMapFig,'off');

        end % PlotPoint


        % Calculate and show T2 values of ROI
        function CalculateRoiT2(app)

            % ysize needed
            if app.parameters.PHASE_ORIENTATION
                ysize = app.dimx;
            else
                ysize = app.dimy;
            end

            for i = 1:size(app.roiPoints,1)
                if app.parameters.PHASE_ORIENTATION
                    t2values(i) = app.t2map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value);%#ok<AGROW> 
                    m0values(i) = app.m0map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value);%#ok<AGROW> 
                    rawdatas(i,:) = app.images(:,ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value)';%#ok<AGROW> 
                else
                    t2values(i) = app.t2map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value);%#ok<AGROW> 
                    m0values(i) = app.m0map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value);%#ok<AGROW> 
                    rawdatas(i,:) = app.images(:,app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField.Value)';%#ok<AGROW> 
                end
            end

            % Averages in ROI or cross-hair value
            r2values = 1./t2values;
            r2values = nonzeros(r2values);
            r2values(isnan(r2values)) = [];
            r2values(isinf(r2values)) = [];
            t2value = 1/mean(r2values);                         % T2 = 1 / mean(R2 values in pixels)
            stdt2value = t2value*std(r2values)/mean(r2values);  % Error propagation with division
            if size(rawdatas,1)>1
                rawdatas(t2values==0,:) = nan;
                app.roiIntData = mean(rawdatas,'omitnan');      % Trick to exclude ROI pixels in background
                rawM0(t2values==0) = nan;
                rawM0 = mean(rawM0); %#ok<NASGU>
            else
                app.roiIntData = rawdatas;
                rawM0 = m0values; %#ok<NASGU>
            end

            % TE selection
            app.MakeTeArray;
            
            nrTEs = length(find(app.teSelection(1:app.ne) == 1));

            % Do a fit of the ROI-averaged pixel intensities to determine M0
            if nrTEs>1
                 [m0roi,~,~] = dotheT2fitSingle(app.roiIntData, app.tes, app.RsquareEditField.Value, app.teSelection);
            end

            % Plot the results of the fits in the chosen pixel
            % app.PlotFits(app.tes, app.roiIntData, t2value, t2roi, stdt2value, m0roi);

            app.PlotFits(app.tes, app.roiIntData, t2value, t2value, stdt2value, m0roi);

        end % CalculateRoiT2


        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app, echotimes, data, t2, t2roi, stdt2, m0)
            
          if app.validRecoFlag  
              
            cla(app.T2linearPlot);
            cla(app.T2logPlot);
            hold(app.T2linearPlot,'on');
            hold(app.T2logPlot,'on');
            
            % Colormap for datapoints
            cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
            tecolorflag = cmap(app.teSelection + 1,:);
            tecolorflag = tecolorflag(1:app.ne,:);
            
            if (t2roi > 0) && (m0 > 0)
                
                % fit
                a = 0 : 0.25: max(echotimes)+5;
                s = m0*exp(-a./t2roi);
                
                % plot linear
                scatter(app.T2linearPlot,app.tes,data,[],tecolorflag,'filled');
                plot(app.T2linearPlot,a,s);
                xlim(app.T2linearPlot,[0, max(echotimes)+5]);
                ylim(app.T2linearPlot,[0, 1.1*max(s)]);
               
                % plot log
                scatter(app.T2logPlot,app.tes,log(data),[],tecolorflag,'filled');
                plot(app.T2logPlot,a,log(s));
                xlim(app.T2logPlot,[0, max(echotimes)+5]);
                ylim(app.T2logPlot,[0.99*min(log(s)), 1.01*max(log(s))]);
            
                % show T2 value in app
                if stdt2 > 0
                    app.T2ViewField.Value = [num2str(t2,'% 10.1f'),' ',char(177),' ',num2str(stdt2,'% 10.1f')];
                else
                    app.T2ViewField.Value = num2str(t2,'% 10.1f');
                end

                app.T2ROIViewField.Value = num2str(t2roi,'% 10.1f');
            
            else

                app.T2ViewField.Value = " ";
                app.T2ROIViewField.Value = " ";

            end
            
            hold(app.T2linearPlot,'off');
            hold(app.T2logPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');

            % sequence parameters
            app.ne = app.parameters.NO_ECHOES;
            app.dimx = app.parameters.NO_SAMPLES;
            app.dimy = app.parameters.NO_VIEWS;
            app.fov = app.parameters.FOV;
            if app.data3dFlag
                app.ns = app.parameters.NO_VIEWS_2;
            else
                app.ns = app.parameters.NO_SLICES;
            end
            if isfield(app.parameters,"EXPERIMENT_ARRAY")
                app.nd = app.parameters.EXPERIMENT_ARRAY;
            else
                app.nd = 1;
            end
            app.tr = app.parameters.tr;
            app.te = app.parameters.te;
            app.esp = app.parameters.te;
            if app.data3dFlag
                app.sl = app.parameters.SLICE_THICKNESS/app.ns;
            else
                app.sl = app.parameters.SLICE_THICKNESS;
            end
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            if strcmp(app.parameters.scanner,"b-type")
                app.tes = app.parameters.echotimes;
                app.esp = app.parameters.echotimes(2)-app.parameters.echotimes(1);
            end
            tetxt = num2str(app.tes');
            for i = 1:10
                tetxt = strrep(tetxt,"  "," ");
            end
            tetxt = strrep(tetxt," "," : ");
            app.TextMessage(strcat("Echo times = ",tetxt," ms ..."));

            % Total acquisition time in seconds
            if isfield(app.rprPars,"TotalAcquisitionTime")
                app.parameters.TotalAcquisitionTime = app.rprPars.TotalAcquisitionTime;
            else
                app.parameters.TotalAcquisitionTime = (app.parameters.tr*app.parameters.NO_VIEWS*app.parameters.NO_AVERAGES*app.parameters.EXPERIMENT_ARRAY)/1000;
            end

            % Orientation
            if isfield(app.parameters,'PHASE_ORIENTATION')
                if app.parameters.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
            end

            % show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TE0EditField.Value = app.te;
            app.ESPEditField.Value = app.esp;
            app.EchoesViewField.Value = app.ne;
            app.DynamicsViewField.Value = app.nd;
            app.DimxViewField.Value = app.dimx;
            app.DimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;

            app.SlicesEditField.Value = 1;
            app.SlicesSlider.Value = 1;
            app.T2MapSlicesEditField.Value = 1;
            app.T2MapSlicesSlider.Value = 1;
            app.M0MapSlicesEditField.Value = 1;
            app.M0MapSlicesSlider.Value = 1;
            app.WaterFatMapSlicesEditField.Value = 1;
            app.WaterFatMapSlicesSlider.Value = 1;

            if app.ns > 1
                app.SlicesEditField.Limits = [1, app.ns];
                app.SlicesSlider.Limits = [1, app.ns];
                app.T2MapSlicesEditField.Limits = [1, app.ns];
                app.T2MapSlicesSlider.Limits = [1, app.ns];
                app.M0MapSlicesEditField.Limits = [1, app.ns];
                app.M0MapSlicesSlider.Limits = [1, app.ns];
                app.WaterFatMapSlicesEditField.Limits = [1, app.ns];
                app.WaterFatMapSlicesSlider.Limits = [1, app.ns];
            else
                app.SlicesEditField.Limits = [1, 2];
                app.SlicesSlider.Limits = [1, 2];
                app.T2MapSlicesEditField.Limits = [1, 2];
                app.T2MapSlicesSlider.Limits = [1, 2];
                app.M0MapSlicesEditField.Limits = [1, 2];
                app.M0MapSlicesSlider.Limits = [1, 2];
                app.WaterFatMapSlicesEditField.Limits = [1, 2];
                app.WaterFatMapSlicesSlider.Limits = [1, 2];
            end

            app.DynamicsEditField.Value = 1;
            app.T2MapDynamicsEditField.Value = 1;
            app.M0MapDynamicsEditField.Value = 1;
            app.WaterFatMapDynamicsEditField.Value = 1;
            app.DynamicsSlider.Value = 1;
            app.T2MapDynamicsSlider.Value = 1;
            app.M0MapDynamicsSlider.Value = 1;
            app.WaterFatMapDynamicsSlider.Value = 1;

            if app.nd > 1
                app.DynamicsEditField.Limits  = [1, app.nd];
                app.T2MapDynamicsEditField.Limits  = [1, app.nd];
                app.M0MapDynamicsEditField.Limits  = [1, app.nd];
                app.WaterFatMapDynamicsEditField.Limits  = [1, app.nd];
                app.DynamicsSlider.Limits  = [1, app.nd];
                app.T2MapDynamicsSlider.Limits  = [1, app.nd];
                app.M0MapDynamicsSlider.Limits  = [1, app.nd];
                app.WaterFatMapDynamicsSlider.Limits  = [1, app.nd];
            else
                app.DynamicsEditField.Limits  = [1, 2];
                app.T2MapDynamicsEditField.Limits  = [1, 2];
                app.M0MapDynamicsEditField.Limits  = [1, 2];
                app.WaterFatMapDynamicsEditField.Limits  = [1, 2];
                app.DynamicsSlider.Limits  = [1, 2];
                app.T2MapDynamicsSlider.Limits  = [1, 2];
                app.M0MapDynamicsSlider.Limits  = [1, 2];
                app.WaterFatMapDynamicsSlider.Limits  = [1, 2];
            end

            app.EchoesViewField.Value = app.ne;
            app.EchoesEditField.Limits = [1, app.ne];
            app.EchoesSlider.Limits = [1, app.ne];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            app.AspectRatioViewField.Value = app.parameters.FOVf/8;
            app.FirstSliceEditField.Value = 1;

            if app.ns > 1
                app.FirstSliceEditField.Limits = [1 app.ns];
                app.LastSliceEditField.Limits = [1 app.ns];
            else
                app.FirstSliceEditField.Limits = [1 2];
                app.LastSliceEditField.Limits = [1 2];
            end

            if app.nd > 1
                app.FirstDynamicEditField.Limits = [1 app.nd];
                app.LastDynamicEditField.Limits = [1 app.nd];
            else
                app.FirstDynamicEditField.Limits = [1 2];
                app.LastDynamicEditField.Limits = [1 2];
            end

            app.LastSliceEditField.Value = app.ns;
            app.LastDynamicEditField.Value = app.nd;
            app.abortFitFlag = false;
            app.abortRegFlag = false;
            
            % File tag
            [~,app.tag,~] = fileparts(app.dataFile);

            % Uncheck/reset some options
            app.CSrecoCheckBox.Value = 0;
            app.DeNoiseCheckBox.Value = 1;
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBox.Value = 0;
            app.MobaCheckBox.Value = 0;

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;

            % Segmentation
            app.structuringElement2D = strel("disk",1);
            app.structuringElement3D = strel("sphere",1);
            app.roiPoints = [];
            
            % reset images
            app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.watermap = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.fatmap = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);
            app.images = zeros(app.ne,app.dimx,app.dimy,app.ns,app.nd);
          
            % reset treshold values
            app.threshold = zeros(app.ns,app.nd);
            
        end % SetParameters


        % make TE array
        function MakeTeArray(app)
            
            app.teSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                ];
            app.teSelection = [app.teSelection ones(1,1024)];
            
        end

        % Set default values for which echoes to include in fitting for spin-echo multi-echo
        function SetSpinEcho(app)
            
                app.CheckBox_1.Value = 0;  app.CheckBox_2.Value = 1;  app.CheckBox_3.Value = 1;  app.CheckBox_4.Value = 1; ...
                app.CheckBox_5.Value = 1;  app.CheckBox_6.Value = 1;  app.CheckBox_7.Value = 1;  app.CheckBox_8.Value = 1; ...
                app.CheckBox_9.Value = 1;  app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; ...
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; ...
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; ...
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; ...
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; ...
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; ...
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; ...
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; ...
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; ...
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; ...
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; ...
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; ...
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; ...
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
            
                app.T2ViewFieldLabel.Text = 'T2 (ms)';
                app.T2MapLabel.Text = 'T2 map';

                app.gradEchoFlag = false;

        end


        % Set default values for which echoes to include in fitting for gradient multi echo
        function SetGradientEcho(app)
                
                app.SetSpinEcho;
                app.CheckBox_1.Value = 1;
                app.gradEchoFlag = true;

                app.T2ViewFieldLabel.Text = 'T2* (ms)';
                app.T2MapLabel.Text = 'T2* map';
     
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)
            
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app, status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % Disable unnecessary warnings
            clc;
            warning off;

            % Set app theme to light
            theme(app.T2mappUIFigure,"light")
            
            % Main window noname
            app.T2mappUIFigure.Name = '';
            
            % Empty file import and export folders
            app.mrdImportFolder = '';
            app.dicomExportFolder = '';
            app.gifExportFolder = '';
            app.roiExportFolder = '';
            app.mrdFlag = true;
            app.data3dFlag = false;
            app.p2roudFlag = false;
            app.gradEchoFlag = false;
                      
            % Add subfolders to search folder
            if ~isdeployed
                app.oldFolder = pwd;
                info = dir(which('T2mapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(pwd));
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));
            end
            
            % Initialize user interface
            app.InitializeUI;

            % Set app colors
            app.ColorsButtonGroupSelectionChanged;
            
            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixFolder, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixFolder)
                        result = [result ';' elastixFolder];
                        setenv('PATH', result);
                    end
                end
            catch
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end
           
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check if Bart was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    rec = bart(app,'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.TextMessage('BART compiled with GPU support ...');
                    app.GPUpresentFlag = true;
                catch
                    app.TextMessage('BART compiled without GPU support ...');
                end
            end

            % Check version
            app.CheckVersionFnc;

            % Start the parallel pool if it is not running already
            try
                app.TextMessage("Starting the parallel pool ...");
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % OK
            app.SetStatus(0);
            app.TextMessage("Ready ...");
            app.SetButtons([1,0,0,0,0,0,0,1]);
         
        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Load the T2 or T2* data file
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % check standard smis folder first if platform is pc, only 1x per session
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('MRD folder found in Windows registry ...');
                    app.TextMessage(strcat("MRD folder = ",folderName," ..."));
                catch 
                    app.TextMessage('Trying standard MRD folder ...');
                    folderName = 'c:\smis\dev\MRD\';
                end
                if exist(folderName, 'dir') && app.mrdFlag
                    app.mrdImportFolder = folderName;
                    app.mrdFlag = false;
                end
            end

            try

                % Reset flags
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validFitFlag = false;
                app.validWaterFatFlag = false;
                app.data3dFlag = false;
                app.p2roudFlag = false;
                app.WaterFatCheckBox.Value = 0;

                % Select a data file
                f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                [app.mrdFile,app.mrdImportFolder] = uigetfile(strcat(app.mrdImportFolder,filesep,{'*.mrd;*.job0;*.*'}));
                delete(f);
                figure(app.T2mappUIFigure);

                if ~ischar(app.mrdFile)
                    ME = MException('T2mapp:UserCancel','User cancel ...');
                    throw(ME);
                end

                if contains(app.mrdFile,"MRD")

                    % MR Solutions data
                    app.TextMessage('Loading MRD k-space data ...');
                    validfile = importMRD(app);
                
                    if ~validfile
                        ME = MException('T2mapp:InvalidFile','Not a valid MRD file ...');
                        throw(ME);
                    end

                    switch app.dataType
                        case "grad-echo"
                            app.SetGradientEcho;
                        case "spin-echo"
                            app.SetSpinEcho;
                    end

                elseif contains(app.mrdFile,"fid")

                    % B-type scanner data
                    app.TextMessage('Loading b-type k-space data ...');
                    app.data = {};
                    [app.data, app.images, app.parameters] = importB(app.mrdImportFolder);
                    app.DataFileViewField.Value = 'fid';
                    app.dataFile = 'fid';
                    if contains(app.parameters.PPL,'flash')
                        app.dataType = "grad-echo";
                        app.SetGradientEcho;
                        app.nrCoils = 1;
                    end

                else

                    ME = MException('T2mapp:InvalidFile','Not a valid data file ...');
                    throw(ME);

                end

                app.SetParameters;

                % Reset buttons
                app.validDataFlag = true;
                app.SetFitButtons(1);
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([1,1,0,0,0,0,0,1]);
                app.SetStatus(0);

                % Do a fast FFT reconstruction
                app.CSrecoCheckBox.Value = 0;
                app.RecoImagesButtonPushed;

                % Show the images
                PlotImageFig(app);

            catch ME

                % Something went wrong during loading of the data
                app.TextMessage(ME.message);
                app.mrdImportFolder = '';
                app.validDataFlag = false;
                app.SetFitButtons(0);
                app.SetLights([0,0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,0,1]);
                app.SetStatus(2);

            end

        end

        % Value changed function: ESPEditField, TE0EditField
        function TEViewFieldValueChanged(app, event)
            
            app.te = app.TE0EditField.Value;
            app.parameters.te = app.TE0EditField.Value;
            app.esp = app.ESPEditField.Value;
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            tetxt = num2str(app.tes');
            for i = 1:10
                tetxt = strrep(tetxt,"  "," ");
            end
            tetxt = strrep(tetxt," "," : ");
            app.TextMessage(strcat("Echo times = ",tetxt," ms ..."));
            
        end

        % Button pushed function: RecoimagesButton
        function RecoImagesButtonPushed(app, event)
            
            if app.validDataFlag
            
                % Indicate busy
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawImagesTab;
            
                % reset some parameters
                app.SlicesEditField.Value = 1;
                app.SlicesSlider.Value = 1;
                app.EchoesEditField.Value = 1;
                app.EchoesSlider.Value = 1;
                app.ThresholdEditField.Value = 1;
                app.ThresholdSlider.Value = 1;
                app.T2MapSlicesEditField.Value = 1;
                app.T2MapSlicesSlider.Value = 1;
                app.M0MapSlicesEditField.Value = 1;
                app.M0MapSlicesSlider.Value = 1;
                app.DynamicsEditField.Value = 1;
                app.T2MapDynamicsEditField.Value = 1;
                app.M0MapDynamicsEditField.Value = 1;
                app.DynamicsSlider.Value = 1;
                app.T2MapDynamicsSlider.Value = 1;
                app.M0MapDynamicsSlider.Value = 1;

                % reset m0 and t2map
                app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);

                try

                    if app.bartDetectedFlag && (app.CSrecoCheckBox.Value == 1)

                        % Perform the BART compressed sensing reconstruction
                        app.TextMessage('CS reconstruction ...');
                        app.images = CSreco(app);

                    else

                        % Perform the FFT reconstruction
                        app.TextMessage('FFT reconstruction ...');
                        [app.images, app.complexImages] = fftReco(app);

                    end

                    % Readout oversampling
                    if isfield(app.parameters,"oversample")
                        if app.parameters.oversample == 1
                            app.TextMessage('Readout oversampling ...');
                            os = round(size(app.images,2)/4);
                            app.images = app.images(:,os+1:end-os,:,:,:);
                        end
                    end

                    % Denoising
                    if app.DeNoiseCheckBox.Value
                        app.TextMessage('PCA denoising ...');
                        app.images = PCAdenoise(app.images,app.DeNoiseWindowEditField.Value);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.TextMessage('Gibbs ringing suppression ...');
                        app.images = unRing(app.images);
                    end

                    % Normalize to convenient range
                    app.images = app.images*32767/max(app.images(:));

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    
                    % Show the images
                    app.SlicesEditField.Value = round(app.ns/2);
                    app.SlicesSlider.Value = round(app.ns/2);
                    PlotImageFig(app);
                    app.PlotMaps;

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.validRecoFlag = false;
                    app.validFitFlag = false;
                    app.validRegFlag = false;

                    % Reset buttons
                    app.SetLights([1,0,0,0,0,0,0]);
                    app.SetButtons([1,1,0,0,0,0,0,1]);
                    app.SetImageSelectionButtons(0);
                    app.SetSegmentationButtons(0);
                    app.SetFitButtons(1);
                    app.SetStatus(2);


                end

            end
    
        end

        % Value changed function: DynamicsEditField, EchoesEditField, 
        % ...and 1 other component
        function ImageSelectionEditFieldValueChanged(app, event)
            
            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.EchoesSlider.Value = app.EchoesEditField.Value;
            app.DynamicsSlider.Value = app.DynamicsEditField.Value;
        
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Value changed function: DynamicsSlider, EchoesSlider, 
        % ...and 1 other component
        function ImageSelectionSliderValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            app.EchoesEditField.Value = round(app.EchoesSlider.Value);
            app.DynamicsEditField.Value = round(app.DynamicsSlider.Value);
              
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
    
            if app.validRecoFlag

                app.validRegFlag = false;
                app.validFitFlag = false;

                try

                    % Indicate busy
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([0,0,0,0,0,0,0,0]);
                    app.SetFitButtons(0);
                    app.SetImageSelectionButtons(0);
                    app.SetSegmentationButtons(0);
                    app.SetMapButtons(0);
                    app.SetStatus(3);
                    app.abortRegFlag = false;
                    app.RegAbortButton.Enable = 'on';

                    % Register all slices to 1st echo time image
                    %if app.bartDetectedFlag
                    %    registerImagesBart(app);
                    %else
                    registerImages(app);
                    %end

                    % reset m0, t2map, threshold
                    app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);
                    app.threshold = zeros(app.ns,app.nd);
                    app.validRegFlag = true;

                    % replot the images
                    app.PlotImageFig;
                    app.PlotMaps;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.RegAbortButton.Enable = 'off';
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(0);
                    app.RegAbortButton.Enable = 'off';
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(2);

                end

            end
            
        end

        % Button pushed function: RegAbortButton
        function RegAbortButtonPushed(app, event)
            
            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.validRecoFlag
                
                % Indicate busy
                app.SetLights([1,1,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for dynamic = 1:app.nd
                        for slice = 1:app.ns
                            maxim = squeeze(app.images(1,:,:,slice,dynamic));
                            app.threshold(slice,dynamic) = graythresh(mat2gray(app.images(1,:,:,slice,dynamic))) * max(maxim(:));
                        end
                    end

                    % Set the automatic mask for all slices
                    app.mask = ones(app.dimx,app.dimy,app.ns);
                    for dynamic = 1:app.nd
                        for slice = 1:app.ns
                            app.mask(:,:,slice,dynamic) = apply_threshold_t2(squeeze(app.images(1,:,:,slice,dynamic)),app.threshold(slice,dynamic));
                        end
                    end

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);

                    % Display the mask in the image
                    PlotImageFig(app);

                    % Indicate busy
                    app.SetLights([1,1,2,1,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(0);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.RecoImagesButtonPushed;

                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Change the value of the threshold for all slices and update the slider
            for slice = 1:app.ns
                app.threshold(slice,:) = app.ThresholdEditField.Value;
            end
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            % Apply the new threshold
            for dynamic = 1:app.nd
                for slice = 1:app.ns
                    app.mask(:,:,slice,dynamic) = apply_threshold_t2(squeeze(app.images(1,:,:,slice,dynamic)),app.threshold(slice,dynamic));
                end
            end

            % Display the result;
            PlotImageFig(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            for dynamic = 1:app.nd
                if app.data3dFlag
                    app.mask(:,:,:,dynamic) = 1-imerode(1-squeeze(app.mask(:,:,:,dynamic)),app.structuringElement3D);
                else
                    for slice = 1:app.ns
                        app.mask(:,:,slice,dynamic) = 1-imerode(1-squeeze(app.mask(:,:,slice,dynamic)),app.structuringElement2D);
                    end
                end
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            for dynamic = 1:app.nd
                if app.data3dFlag
                    app.mask(:,:,:,dynamic) = 1-imdilate(1-squeeze(app.mask(:,:,:,dynamic)),app.structuringElement3D);
                else
                    for slice = 1:app.ns
                    app.mask(:,:,slice,dynamic) = 1-imdilate(1-squeeze(app.mask(:,:,slice,dynamic)),app.structuringElement2D);
                    end
                end
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Morphological closing on current slice
            for dynamic = 1:app.nd
                if app.data3dFlag
                    app.mask(:,:,:,dynamic) = 1-imdilate(1-squeeze(app.mask(:,:,:,dynamic)),app.structuringElement3D);
                    app.mask(:,:,:,dynamic) = 1-imerode(1-squeeze(app.mask(:,:,:,dynamic)),app.structuringElement3D);
                else
                    for slice = 1:app.ns
                    app.mask(:,:,slice,dynamic) = 1-imdilate(1-squeeze(app.mask(:,:,slice,dynamic)),app.structuringElement2D);
                    app.mask(:,:,slice,dynamic) = 1-imerode(1-squeeze(app.mask(:,:,slice,dynamic)),app.structuringElement2D);
                    end
                end
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Morphological open on current slice
            for dynamic = 1:app.nd
                if app.data3dFlag
                    app.mask(:,:,:,dynamic) = 1-imopen(1-squeeze(app.mask(:,:,:,dynamic)),app.structuringElement3D);
                else
                    for slice = 1:app.ns
                    app.mask(:,:,slice,dynamic) = 1-imopen(1-squeeze(app.mask(:,:,slice,dynamic)),app.structuringElement2D);
                    end
                end
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             
            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)
            
            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end
            
        end

        % Button pushed function: FitT2Button
        function FitT2ButtonPushed(app, event)
            
            if app.validRecoFlag

                app.SetLights([2,2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T2 maps ....');
                app.validFitFlag = false;
                app.validWaterFatFlag = false;
                app.abortFitFlag = false;
                app.FitAbortButton.Enable = 'on';
                drawnow;

                try

                    % TE selection
                    app.MakeTeArray;
                    nr_tes = length(find(app.teSelection(1:app.ne) == 1));

                    % Some checks
                    if (app.te <= 0) || (app.esp <= 0)
                        ME = MException('T2mapp:TEerror','ERROR: TE0 and ESP should be > 0 for fitting ...');
                        throw(ME);
                    end

                    if nr_tes == 0
                        ME = MException('T2mapp:EchoError','ERROR: Number of echoes should be greater than 1 ...');
                        throw(ME);
                    end

                    if app.bartDetectedFlag && app.MobaCheckBox.Value

                        % -------------------------------------------------------
                        %     Model-based fitting
                        % -------------------------------------------------------

                        app.TextMessage("Model based fitting ...");

                        if app.data3dFlag

                            % Progress counter reset
                            cnt = 1;
                            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1);

                            % Timing parameters
                            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                            elapsedTime = 0;
                            app.FitProgressGauge.Value = 0;
               
                            % For selected dynamics
                            dynamic = app.FirstDynamicEditField.Value - 1;

                            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                                dynamic = dynamic + 1;

                                tic;

                                app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                drawnow;

                                % Do the T2 or T2* fitting
                                switch app.dataType

                                    case "spin-echo"

                                        [app.m0map(:,:,:,dynamic),app.t2map(:,:,:,dynamic)] = dothemobaT2fit3D(app,dynamic);
                                        app.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.r2map(:,:,:,dynamic))));

                                    case "grad-echo"

                                        [app.m0map(:,:,:,dynamic),app.t2map(:,:,:,dynamic)] = dothemobaT2starfit3D(app,dynamic);
                                        app.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.r2map(:,:,:,dynamic))));

                                end

                                % Plot T2 map thus far with auto scale
                                try
                                    scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                    scale(scale<5)=5;
                                catch
                                    scale = 100;
                                    
                                end
                                app.T2ScaleEditField.Value = scale;

                                app.validFitFlag = true;
                                app.PlotMaps;
                                app.validFitFlag = false;

                                % Update progresss bar
                                app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                                drawnow;

                                % Update the timing indicator
                                elapsedTime = elapsedTime + toc;
                                estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                timeRemaining(timeRemaining<0) = 0;
                                app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                drawnow;

                                cnt = cnt + 1;

                            end % Loop over dynamics

                        else

                            % Progress counter reset
                            cnt = 1;
                            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                                (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                            % Timing parameters
                            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                            elapsedTime = 0;
                            app.FitProgressGauge.Value = 0;

                            % For selected dynamics
                            dynamic = app.FirstDynamicEditField.Value - 1;

                            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                                dynamic = dynamic + 1;

                                % for selected slices
                                slice = app.FirstSliceEditField.Value - 1;

                                while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                                    slice = slice + 1;

                                    tic;

                                    app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                                    app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                    drawnow;

                                    % Do the T2 or T2* fitting
                                    switch app.dataType

                                        case "spin-echo"

                                            [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic)] = dothemobaT2fit2D(app,slice,dynamic);
                                            app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));

                                        case "grad-echo"

                                            [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic)] = dothemobaT2starfit2D(app,slice,dynamic);
                                            app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));

                                    end

                                    % Plot T2 map thus far with auto scale
                                    try
                                        scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                        scale(scale<5)=5;
                                        app.T2ScaleEditField.Value = scale;
                                    catch
                                        scale = 100;
                                        app.T2ScaleEditField.Value = scale;
                                    end

                                    app.validFitFlag = true;
                                    app.PlotMaps;
                                    app.validFitFlag = false;

                                    % Update progresss bar
                                    app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps );

                                    % Update the timing indicator
                                    elapsedTime = elapsedTime + toc;
                                    estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                    timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                    timeRemaining(timeRemaining<0) = 0;
                                    app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                    drawnow;

                                    cnt = cnt + 1;

                                end % Loop over slices

                            end % Loop over dynamics

                        end % 3D or 2D

                    else

                        % ---------------------------------------
                        %     Normal pixel-wise fitting
                        % ---------------------------------------

                        % Progress counter reset
                        cnt = 1;
                        totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                            (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                        % Timing parameters
                        app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                        elapsedTime = 0;
                        app.FitProgressGauge.Value = 0;

                        % For selected dynamics
                        dynamic = app.FirstDynamicEditField.Value - 1;

                        while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                            dynamic = dynamic + 1;

                            % for selected slices
                            slice = app.FirstSliceEditField.Value - 1;

                            while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                                slice = slice + 1;

                                tic;

                                app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                                app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                drawnow;

                                % Do the fit for 1 slice
                                [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic),app.r2map(:,:,slice,dynamic)] = dotheT2fit2D(app,slice,dynamic);

                                % Plot T2 map thus far with auto scale
                                try
                                    scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                    scale(scale<5)=5;
                                    app.T2ScaleEditField.Value = scale;
                                catch
                                    scale = 100;
                                    app.T2ScaleEditField.Value = scale;
                                end

                                app.validFitFlag = true;
                                app.PlotMaps;
                                app.validFitFlag = false;

                                % Update progresss bar
                                app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                              
                                % Update the timing indicator
                                elapsedTime = elapsedTime + toc;
                                estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                timeRemaining(timeRemaining<0) = 0;
                                app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                drawnow;

                                cnt = cnt + 1;
                             
                            end % Loop over slices

                        end % Loop over dynamics

                    end % MOBA true / false

                    app.TextMessage('Finished ... ');
                    app.EstimatedFitTimeViewField.Value = 'Finished ...';


                    % ---------------------------------------
                    %     Water - Fat imaging
                    % ---------------------------------------

                    if app.WaterFatCheckBox.Value

                        % Progress counter reset
                        cnt = 1;
                        totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                            (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                        % Timing parameters
                        app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                        elapsedTime = 0;
                        app.FitProgressGauge.Value = 0;

                        % For selected dynamics
                        dynamic = app.FirstDynamicEditField.Value - 1;

                        while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                            dynamic = dynamic + 1;

                            % for selected slices
                            slice = app.FirstSliceEditField.Value - 1;

                            while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                                slice = slice + 1;

                                tic;

                                app.TextMessage(sprintf("Water/fat fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                                app.TextMessage(sprintf("Water/fat fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                drawnow;

                                % Do the water and fat map for 1 slice
                                [app.watermap(:,:,slice,dynamic),app.fatmap(:,:,slice,dynamic)] = waterFatImagesMethod1(app, slice, dynamic);

                                % Plot the images so far
                                app.validFitFlag = true;
                                app.PlotMaps;
                                app.validFitFlag = false;

                                % Update progresss bar
                                app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);

                                % Update the timing indicator
                                elapsedTime = elapsedTime + toc;
                                estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                timeRemaining(timeRemaining<0) = 0;
                                app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                drawnow;

                                % At least 1 water-fat image made
                                app.validWaterFatFlag = true;

                                cnt = cnt + 1;

                            end % Loop over slices

                        end % Loop over dynamics

                        app.TextMessage('Finished ... ');
                        app.EstimatedFitTimeViewField.Value = 'Finished ...';

                    end

                    % Reset buttons
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);
                    app.validFitFlag = true;
                    app.FitAbortButton.Enable = 'off';

                    % Show middle slice
                    app.ChangeMapSlices(round(app.ns/2));
                    app.PlotMaps;

               catch ME

                    app.TextMessage(ME.message)
                    app.TextMessage("ERROR: Something went wrong during fitting ...");

                    % Reset buttons
                    app.SetLights([2,2,2,2,0,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(2);
                    app.validFitFlag = false;
                    app.validWaterFatFlag = false;
                    app.FitAbortButton.Enable = 'off';

               end

            end
         
        end

        % Button pushed function: FitAbortButton
        function FitAbortButtonPushed(app, event)
            
            app.abortFitFlag = true; 
            drawnow;
        
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function SliceRangeEditFieldValueChanged(app, event)
            
            app.FirstSliceEditField.Value(app.FirstSliceEditField.Value > app.ns) = app.ns;
            app.LastSliceEditField.Value(app.LastSliceEditField.Value > app.ns) = app.ns;

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function DynamicRangeEditFieldValueChanged(app, event)
                        
            app.LastDynamicEditField.Value(app.LastDynamicEditField.Value > app.nd) = app.nd;
            app.FirstDynamicEditField.Value(app.FirstDynamicEditField.Value > app.nd) = app.nd;
            
            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end
            
        end

        % Value changed function: T2ScaleEditField
        function T2ScaleEditFieldValueChanged(app, event)
            
            % Plot the T2 map with the new scale
            app.PlotMaps;
            
        end

        % Button pushed function: T2ScaleAutoButton
        function T2ScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the T2 map
            % Auto-scale = 2 x mean of the T2 values in the image
            scale = round(2*mean(nonzeros(squeeze(app.t2map(app.T2MapSlicesEditField.Value,:))))); 
            scale(isnan(scale))=100;
            scale(scale<5)=5;
            app.T2ScaleEditField.Value = scale;
            
            % Plot the map
            app.PlotMaps;
            
        end

        % Window scroll wheel function: T2mappUIFigure
        function T2MappUIFigureWindowScrollWheel(app, event)
            
            if app.ns > 1

                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos < 820

                    if app.validRecoFlag

                        if verticalScrollCount > 0
                            newslice = app.SlicesEditField.Value + 1;
                            newslice(newslice>app.SlicesEditField.Limits(2)) = app.SlicesEditField.Limits(2);
                        else
                            newslice = app.SlicesEditField.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.SlicesEditField.Value = newslice;
                        app.ImageSelectionEditFieldValueChanged;

                    end

                else

                    if ~app.roiFlag

                        if app.validFitFlag

                            if verticalScrollCount > 0
                                newslice = app.T2MapSlicesEditField.Value + 1;
                                newslice(newslice>app.T2MapSlicesEditField.Limits(2)) = app.T2MapSlicesEditField.Limits(2);
                            else
                                newslice = app.T2MapSlicesEditField.Value - 1;
                                newslice(newslice<1) = 1;
                            end

                            app.T2MapSlicesEditField.Value = newslice;
                            app.M0MapSlicesEditField.Value = newslice;
                            app.T2MapSlicesEditFieldValueChanged;
                            app.M0MapSlicesEditFieldValueChanged;

                        end

                    end

                end

                drawnow;

            end
                        
        end

        % Selection changed function: T2ColormapButtonGroup
        function T2ColormapButtonGroup1SelectionChanged(app, event)
            
            if app.grayButton1.Value == 1 
                app.t2cmap = gray; 
            end
            
            if app.autumnButton1.Value == 1
                app.t2cmap = autumn; 
            end
            
            if app.winterButton1.Value == 1
                app.t2cmap = winter; 
            end
            
            if app.parulaButton1.Value == 1
                app.t2cmap = parula; 
            end
            
            if app.naviaButton1.Value == 1
                app.t2cmap = naviaColorMap(); 
            end
            
            % Background should always be black
            app.t2cmap(1,:) = [0 0 0];
            
            % Plot the T2 map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: M0ColorMapButtonGroup
        function M0ColorMapButtonGroupSelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.naviaButton2.Value == 1
                app.m0cmap = naviaColorMap(); 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: R2ColorMapButtonGroup
        function R2ColorMapButtonGroupSelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.naviaButton3.Value == 1
                app.r2cmap = naviaColorMap(); 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            app.PlotMaps;
            
            
        end

        % Selection changed function: WaterColorMapButtonGroup
        function WaterColorMapButtonGroupSelectionChanged(app, event)

            if app.grayButton4.Value == 1
                app.watercmap = gray;
            end

            if app.autumnButton4.Value == 1
                app.watercmap = autumn;
            end

            if app.winterButton4.Value == 1
                app.watercmap = winter;
            end

            if app.parulaButton4.Value == 1
                app.watercmap = parula;
            end

            if app.naviaButton4.Value == 1
                app.watercmap = naviaColorMap(); 
            end

            % Background should always be black
            app.watercmap(1,:) = [0 0 0];

            % Plot the R2 map with the new colormap
            app.PlotMaps;
            
        end

        % Selection changed function: FatColorMapButtonGroup
        function FatColorMapButtonGroupSelectionChanged(app, event)

            if app.grayButton5.Value == 1
                app.fatcmap = gray;
            end

            if app.autumnButton5.Value == 1
                app.fatcmap = autumn;
            end

            if app.winterButton5.Value == 1
                app.fatcmap = winter;
            end

            if app.parulaButton5.Value == 1
                app.fatcmap = parula;
            end

            if app.naviaButton5.Value == 1
                app.fatcmap = naviaColorMap();
            end

            % Background should always be black
            app.fatcmap(1,:) = [0 0 0];

            % Plot the R2 map with the new colormap
            app.PlotMaps;
            
        end

        % Image clicked function: M0Image, T2Image, WaterImage
        function T2ImageClicked(app, event)

            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value;
                    xsize = app.dimy;
                    ysize = app.dimx;
                else
                    aratio = 1/(app.AspectRatioViewField.Value);
                    xsize = app.dimx;
                    ysize = app.dimy;
                end

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.T2MapSlicesEditField.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.T2XEditField.Value = xcor;
                app.T2YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.T2BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.T2BrushEditField.Value-1,app.T2BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Show the resulting T2 values and fits
                app.CalculateRoiT2;

            end
               
        end

        % Button pushed function: M0ROIButton, T2ROIbutton
        function ROIbuttonButtonPushed(app, event)
             
            % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.T2ROIbutton.Text,'ROI')
                 
                 app.T2ROIbutton.Text = 'Cross';
                 app.M0ROIButton.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.T2ROIEraseButton.Visible = 'on';
                 app.M0ROIEraseButton.Visible = 'on';
                 app.T2ROIViewField.Visible = 'on';
                 app.T2PixelValueLabel.Text = 'Pixel averaged';
                 app.T2ROIViewFieldLabel.Visible = 'on';
                 app.T2BrushEditField.Visible = 'on';
                 app.T2BrushEditFieldLabel.Visible = 'on';
                 app.M0BrushEditField.Visible = 'on';
                 app.M0BrushEditFieldLabel.Visible = 'on';
                 app.roiFlag = true;

             else

                 app.T2ROIbutton.Text = 'ROI';
                 app.M0ROIButton.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.T2ROIEraseButton.Visible = 'off';
                 app.M0ROIEraseButton.Visible = 'off';
                 app.T2ROIViewField.Visible = 'off';
                 app.T2PixelValueLabel.Text = 'Pixel value';
                 app.T2ROIViewFieldLabel.Visible = 'off';
                 app.T2BrushEditField.Visible = 'off';
                 app.T2BrushEditFieldLabel.Visible = 'off';
                 app.M0BrushEditField.Visible = 'off';
                 app.M0BrushEditFieldLabel.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roiT2);
             delete(app.roiM0);
             delete(app.crossT2);
             delete(app.crossM0);
             app.PlotMaps;
             
        end

        % Value changed function: T2BrushEditField
        function T2BrushEditFieldValueChanged(app, event)
            
            app.M0BrushEditField.Value = app.T2BrushEditField.Value;
            
        end

        % Value changed function: M0BrushEditField
        function M0BrushEditFieldValueChanged(app, event)
        
            app.T2BrushEditField.Value = app.M0BrushEditField.Value;
            
        end

        % Button pushed function: M0ROIEraseButton, T2ROIEraseButton
        function EraseButtonPushed(app, event)
            
            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);
            app.PlotMaps;
            
        end

        % Button pushed function: T2ExportRoiDataButton
        function T2ExportRoiDataButtonPushed(app, event)

            try

                if app.validFitFlag && ~isempty(app.roiPoints)

                    app.T2mappUIFigure.Visible = 'off';
                    app.roiExportFolder = uigetdir(app.roiExportFolder,'Raw data export folder ...');
                    app.T2mappUIFigure.Visible = 'on';

                    if ~ischar(app.roiExportFolder)
                        app.roiExportFolder = '';
                        ME = MException('T2mapp:userCancel','User cancel ...');
                        throw(ME);
                    end

                    rdata = [app.tes app.roiIntData'];
                    writematrix(rdata,strcat(app.roiExportFolder,filesep,'roiData.csv'));
                    app.TextMessage('Exporting image intensities from ROI ...');

                end

                app.SetStatus(0);

            catch ME

                app.TextMessage(ME.message);
                app.SetStatus(2);

            end

        end

        % Value changed function: WaterFatMapSlicesEditField
        function WFmapSlicesEditFieldValueChanged(app, event)
            
            value = app.WaterFatMapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: WaterFatMapSlicesSlider
        function WFmapSlicesSliderValueChange(app, event)
            
            value = app.WaterFatMapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: WaterFatMapDynamicsEditField
        function WFmapDynamicsEditFieldValueChanged(app, event)
            
            value = app.WaterFatMapDynamicsEditField.Value;
            
            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;

        end

        % Value changed function: WaterFatMapDynamicsSlider
        function WFmapDynamicsSliderValueChanged(app, event)
            
            value = app.WaterFatMapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: T2MapSlicesEditField
        function T2MapSlicesEditFieldValueChanged(app, event)
            
            value = app.T2MapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: T2MapSlicesSlider
        function T2MapSlicesSliderValueChanged(app, event)
            
            value = app.T2MapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: T2MapDynamicsEditField
        function T2MapDynamicsEditFieldValueChanged(app, event)
            
            value = app.T2MapDynamicsEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: T2MapDynamicsSlider
        function T2MapDynamicsSliderValueChanged(app, event)
            
            value = app.T2MapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: M0MapSlicesEditField
        function M0MapSlicesEditFieldValueChanged(app, event)
            
            value = app.M0MapSlicesEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: M0MapSlicesSlider
        function M0MapSlicesSliderValueChanged(app, event)
            
            value = app.M0MapSlicesSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapSlices(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: M0MapDynamicsEditField
        function M0MapDynamicsEditFieldValueChanged(app, event)
            
            value = app.M0MapDynamicsEditField.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Value changed function: M0MapDynamicsSlider
        function M0MapDynamicsSliderValueChanged(app, event)
            
            value = app.M0MapDynamicsSlider.Value;

            % Change the slider value according to the edit number
            app.ChangeMapDynamics(value);
            
            % Plot the T2-map for the new slice and dynamic
            app.PlotMaps;
            
        end

        % Button pushed function: DCMNIFTIButton
        function ExportDCMNIFTIButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,0,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data folder share the same base-folder
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSfolder{1} = replace(app.mrdImportFolder,"MRD","Data");
                    try
                        k = strfind(dcmSfolder{1},"Data");
                        fs = strfind(dcmSfolder{1}(k:end),filesep);
                        dcmSfolder{1}= dcmSfolder{1}(1:k+fs(3)-1);
                        dcmSfolder{1} = insertAfter(dcmSfolder{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSfolder{1} = app.mrdImportFolder;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSfolder{2} = strcat(dcmSfolder{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSfolder{3} = strcat(dcmSfolder{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSfolder{4} = strcat(dcmSfolder{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the folders contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSfolder)
                        if ~isempty(dir(strcat(dcmSfolder{i},"*.dcm")))
                            dcmFolder = dcmSfolder{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmFolder));
                            filesepIdx = strfind(dcmFolder, filesep);
                            app.dicomExportFolder = dcmFolder(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist
                        
                        % Dicom export using Dicom information
                        try
                            % Check if folder is actually writable
                            mkdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                            rmdir(strcat(app.dicomExportFolder,filesep,"testfolder"));
                        catch
                            app.T2mappUIFigure.Visible = 'off';
                            app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                            app.T2mappUIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportFolder)
                                ME = MException('T2mapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        app.TextMessage('Export with DICOM information ...');
                        app.TextMessage(strcat("DICOM export folder = ",app.dicomExportFolder," ..."));
                        app.exportFolder = exportDicomT2dcm(app, dcmFolder);

                    else

                        % Dicom export without dicom information
                        app.T2mappUIFigure.Visible = 'off';
                        app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                        app.T2mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportFolder)
                            ME = MException('T2mapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.exportFolder = strcat(app.dicomExportFolder,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export folder = ",app.exportFolder," ..."));
                        app.exportFolder = exportDicomT2(app);
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % NIFTI export
                    try
                        exportNiftiFcn(app);
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfti export failed ...");
                    end

                    % Success
                    app.TextMessage("DICOM export ready ...");
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during Dicom export ...');
                    app.dicomExportFolder = '';
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: GIFButton
        function ExportGIFbuttonPushed(app, event)

            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,2,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    try
                        % Make the \data\scan_number\folder
                        gifOutputFolder = replace(app.mrdImportFolder, "MRD", "Data");
                        k = strfind(gifOutputFolder,"Data");
                        fs = strfind(gifOutputFolder(k:end),filesep);
                        gifOutputFolder = gifOutputFolder(1:k+fs(2)-1);
                        % Check if folder is actually writable
                        mkdir(strcat(gifOutputFolder,filesep,"testfolder"));
                        rmdir(strcat(gifOutputFolder,filesep,"testfolder"));
                    catch
                        app.T2mappUIFigure.Visible = 'off';
                        app.gifExportFolder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        app.T2mappUIFigure.Visible = 'on';
                        if ~ischar(app.gifExportFolder)
                            ME = MException('T2map:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifOutputFolder = app.gifExportFolder;
                    end
                    gifOutputFolder = strcat(gifOutputFolder,filesep,"GIF",filesep);

                    % Export to animated gif
                    app.TextMessage(strcat("GIF export folder = ",gifOutputFolder," ..."));
                    exportGifT2(app, gifOutputFolder);
                   
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.gifExportFolder = '';
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search folder
            if (~isdeployed)
                info = dir(which('T2mapp.mlapp'));
                app.appFolder = info.folder;
                rmpath(genpath(app.appFolder));
                cd(app.oldFolder);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T2mappUIFigure = uifigure('Visible', 'off');
            app.T2mappUIFigure.Name = 'T2mapp';
            app.T2mappUIFigure.Icon = fullfile(pathToMLAPP, 'T2icon.png');
            app.T2mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T2MappUIFigureWindowScrollWheel, true);
            app.T2T2mappingLabel = uilabel(app.T2mappUIFigure);
            app.T2T2mappingLabel.Text = 'T2 & T2* mapping';
            app.CreditsLabel = uilabel(app.T2mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.T2mappUIFigure);
            app.MessageBox = uitextarea(app.T2mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.BackgroundBox = uitextarea(app.T2mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.T2mappUIFigure);
            app.StatusLabel = uilabel(app.T2mappUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.MRSImage = uiimage(app.T2mappUIFigure);
            app.TabGroup1 = uitabgroup(app.T2mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load MRD multi-echo or multi-gradient echo data'};
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.RecoimagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoimagesButton.Enable = 'off';
            app.RecoimagesButton.Tooltip = {'Reconstruct the images, check CS reco for compressed-sensing reconstruction'};
            app.RecoimagesButton.Text = 'Reco images';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Registration of images to 1st echo image'};
            app.RegisterButton.Text = 'Register';
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask image to prevent fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.FitT2Button = uibutton(app.DashboardTab, 'push');
            app.FitT2Button.ButtonPushedFcn = createCallbackFcn(app, @FitT2ButtonPushed, true);
            app.FitT2Button.Enable = 'off';
            app.FitT2Button.Tooltip = {'T2 or T2* fitting of the data. Check model-based for model-based fitting with Bart. Check water-fat for T2* based water-fat imaging'};
            app.FitT2Button.Text = 'Fit T2';
            app.DCMNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to DICOM'};
            app.DCMNIFTIButton.Text = 'DCM / NIFTI';
            app.GIFButton = uibutton(app.DashboardTab, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFbuttonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to animated GIF'};
            app.GIFButton.Text = 'GIF';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit'};
            app.ExitButton.Text = 'Exit';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp7 = uilamp(app.DashboardTab);
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitAbortButton = uibutton(app.DashboardTab, 'push');
            app.FitAbortButton.ButtonPushedFcn = createCallbackFcn(app, @FitAbortButtonPushed, true);
            app.FitAbortButton.Enable = 'off';
            app.FitAbortButton.Tooltip = {'Abort fitting'};
            app.FitAbortButton.Text = 'Abort';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegAbortButton = uibutton(app.DashboardTab, 'push');
            app.RegAbortButton.ButtonPushedFcn = createCallbackFcn(app, @RegAbortButtonPushed, true);
            app.RegAbortButton.Enable = 'off';
            app.RegAbortButton.Tooltip = {'Abort registration'};
            app.RegAbortButton.Text = 'Abort';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation type'};
            app.RegistrationDropDown.Value = 'Translation';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.WaterFatCheckBoxLabel = uilabel(app.DashboardTab);
            app.WaterFatCheckBoxLabel.Enable = 'off';
            app.WaterFatCheckBoxLabel.Tooltip = {'Check for T2*-based water-fat separation'};
            app.WaterFatCheckBoxLabel.Text = 'water/fat';
            app.WaterFatCheckBox = uicheckbox(app.DashboardTab);
            app.WaterFatCheckBox.Tooltip = {'Check for T2*-based water-fat separation'};
            app.WaterFatCheckBox.Enable = 'off';
            app.WaterFatCheckBox.Text = '';
            app.MobaCheckBoxLabel = uilabel(app.DashboardTab);
            app.MobaCheckBoxLabel.Enable = 'off';
            app.MobaCheckBoxLabel.Tooltip = {'Check for model-based T2 or T2* fitting with Bart'};
            app.MobaCheckBoxLabel.Text = 'model-based';
            app.MobaCheckBox = uicheckbox(app.DashboardTab);
            app.MobaCheckBox.Tooltip = {'Check for model-based T2 or T2* fitting with Bart'};
            app.MobaCheckBox.Enable = 'off';
            app.MobaCheckBox.Text = '';
            app.CSrecoCheckBoxLabel = uilabel(app.DashboardTab);
            app.CSrecoCheckBoxLabel.Enable = 'off';
            app.CSrecoCheckBoxLabel.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBoxLabel.Text = 'CS reco';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Text = '';
            app.TabGroup3 = uitabgroup(app.T2mappUIFigure);
            app.T2mapTab = uitab(app.TabGroup3);
            app.T2mapTab.Title = 'T2-map';
            app.T2MapFig = uiaxes(app.T2mapTab);
            app.T2MapFig.XTick = [];
            app.T2MapFig.XTickLabelRotationMode = 'manual';
            app.T2MapFig.YTick = [];
            app.T2MapFig.YTickLabelRotationMode = 'manual';
            app.T2MapFig.ZTickLabelRotationMode = 'manual';
            app.T2logPlot = uiaxes(app.T2mapTab);
            xlabel(app.T2logPlot, 'TE')
            ylabel(app.T2logPlot, 'log ( Signal )')
            app.T2logPlot.XTickLabelRotationMode = 'manual';
            app.T2logPlot.YTickLabelRotationMode = 'manual';
            app.T2logPlot.ZTickLabelRotationMode = 'manual';
            app.T2linearPlot = uiaxes(app.T2mapTab);
            xlabel(app.T2linearPlot, 'TE')
            ylabel(app.T2linearPlot, 'Signal')
            app.T2linearPlot.XTickLabelRotationMode = 'manual';
            app.T2linearPlot.YTickLabelRotationMode = 'manual';
            app.T2linearPlot.ZTickLabelRotationMode = 'manual';
            app.T2MapLabel = uilabel(app.T2mapTab);
            app.T2MapLabel.Text = 'T2 map';
            app.T2ImageSelectionPanel = uipanel(app.T2mapTab);
            app.T2ImageSelectionPanel.Title = 'Image selection';
            app.T2MapSlicesEditField = uieditfield(app.T2ImageSelectionPanel, 'numeric');
            app.T2MapSlicesEditField.Limits = [1 Inf];
            app.T2MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesEditFieldValueChanged, true);
            app.T2MapSlicesEditField.Tooltip = {'Slice'};
            app.T2MapSlicesEditField.Value = 1;
            app.T2MapSlicesSlider = uislider(app.T2ImageSelectionPanel);
            app.T2MapSlicesSlider.Limits = [1 200];
            app.T2MapSlicesSlider.MajorTicks = [];
            app.T2MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @T2MapSlicesSliderValueChanged, true);
            app.T2MapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.T2MapSlicesSlider.Tooltip = {'Slice'};
            app.T2MapSlicesSlider.Value = 1;
            app.T2MapDynamicsEditField = uieditfield(app.T2ImageSelectionPanel, 'numeric');
            app.T2MapDynamicsEditField.Limits = [1 Inf];
            app.T2MapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.T2MapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @T2MapDynamicsEditFieldValueChanged, true);
            app.T2MapDynamicsEditField.Tooltip = {'Dynamic'};
            app.T2MapDynamicsEditField.Value = 1;
            app.T2MapDynamicsSlider = uislider(app.T2ImageSelectionPanel);
            app.T2MapDynamicsSlider.Limits = [1 200];
            app.T2MapDynamicsSlider.MajorTicks = [];
            app.T2MapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @T2MapDynamicsSliderValueChanged, true);
            app.T2MapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.T2MapDynamicsSlider.Tooltip = {'Dynamic'};
            app.T2MapDynamicsSlider.Value = 1;
            app.T2MapSlicesEditFieldLabel = uilabel(app.T2ImageSelectionPanel);
            app.T2MapSlicesEditFieldLabel.Text = 'slice';
            app.T2MapDynamicsEditFieldLabel = uilabel(app.T2ImageSelectionPanel);
            app.T2MapDynamicsEditFieldLabel.Text = 'dynamic';
            app.T2ColormapButtonGroup = uibuttongroup(app.T2mapTab);
            app.T2ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @T2ColormapButtonGroup1SelectionChanged, true);
            app.T2ColormapButtonGroup.Title = 'Color map';
            app.grayButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.grayButton1.Text = 'gray';
            app.grayButton1.Interpreter = 'html';
            app.autumnButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.autumnButton1.Text = 'autumn';
            app.autumnButton1.Interpreter = 'html';
            app.winterButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.winterButton1.Text = 'winter';
            app.winterButton1.Interpreter = 'html';
            app.parulaButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.parulaButton1.Text = 'parula';
            app.parulaButton1.Interpreter = 'html';
            app.naviaButton1 = uiradiobutton(app.T2ColormapButtonGroup);
            app.naviaButton1.Text = 'navia';
            app.naviaButton1.Interpreter = 'html';
            app.naviaButton1.Value = true;
            app.T2ScaleMaxLabel = uilabel(app.T2ColormapButtonGroup);
            app.T2ScaleMaxLabel.Text = 'Max';
            app.T2ScaleEditField = uieditfield(app.T2ColormapButtonGroup, 'numeric');
            app.T2ScaleEditField.Limits = [1 Inf];
            app.T2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T2ScaleEditFieldValueChanged, true);
            app.T2ScaleEditField.Tooltip = {'Scale max'};
            app.T2ScaleEditField.Value = 1;
            app.T2ScaleAutoButton = uibutton(app.T2ColormapButtonGroup, 'push');
            app.T2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T2ScaleAutoButtonPushed, true);
            app.T2ScaleAutoButton.Tooltip = {'Auto scale'};
            app.T2ScaleAutoButton.Text = 'Auto';
            app.T2Image = uiimage(app.T2mapTab);
            app.T2Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.T2XEditFieldLabel = uilabel(app.T2mapTab);
            app.T2XEditFieldLabel.Text = 'X';
            app.T2XEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2XEditField.ValueDisplayFormat = '%.0f';
            app.T2XEditField.Editable = 'off';
            app.T2XEditField.Tooltip = {'X-coordinate'};
            app.T2YEditFieldLabel = uilabel(app.T2mapTab);
            app.T2YEditFieldLabel.Text = 'Y';
            app.T2YEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2YEditField.ValueDisplayFormat = '%.0f';
            app.T2YEditField.Editable = 'off';
            app.T2YEditField.Tooltip = {'Y-coordinate'};
            app.T2ROIbutton = uibutton(app.T2mapTab, 'push');
            app.T2ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.T2ROIbutton.Tooltip = {'Cross-hair or ROI drawing'};
            app.T2ROIbutton.Text = 'ROI';
            app.T2ROIEraseButton = uibutton(app.T2mapTab, 'push');
            app.T2ROIEraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.T2ROIEraseButton.Visible = 'off';
            app.T2ROIEraseButton.Tooltip = {'Erase ROI'};
            app.T2ROIEraseButton.Text = 'Erase';
            app.T2ViewField = uieditfield(app.T2mapTab, 'text');
            app.T2ViewField.Editable = 'off';
            app.T2ViewField.Tooltip = {'T2 value'};
            app.T2ViewFieldLabel = uilabel(app.T2mapTab);
            app.T2ViewFieldLabel.Text = 'T2 (ms)';
            app.T2ROIViewField = uieditfield(app.T2mapTab, 'text');
            app.T2ROIViewField.Editable = 'off';
            app.T2ROIViewField.Visible = 'off';
            app.T2ROIViewField.Tooltip = {'ROI-averaged T2 value'};
            app.T2PixelValueLabel = uilabel(app.T2mapTab);
            app.T2PixelValueLabel.Text = 'pixel value';
            app.T2ROIViewFieldLabel = uilabel(app.T2mapTab);
            app.T2ROIViewFieldLabel.Visible = 'off';
            app.T2ROIViewFieldLabel.Text = 'ROI averaged';
            app.T2ExportRoiDataButton = uibutton(app.T2mapTab, 'push');
            app.T2ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @T2ExportRoiDataButtonPushed, true);
            app.T2ExportRoiDataButton.Tooltip = {'Export T2 as function of TE in ROI to CSV-file'};
            app.T2ExportRoiDataButton.Text = 'Export';
            app.T2BrushEditFieldLabel = uilabel(app.T2mapTab);
            app.T2BrushEditFieldLabel.Visible = 'off';
            app.T2BrushEditFieldLabel.Text = 'brush';
            app.T2BrushEditField = uieditfield(app.T2mapTab, 'numeric');
            app.T2BrushEditField.Limits = [1 5];
            app.T2BrushEditField.ValueDisplayFormat = '%.0f';
            app.T2BrushEditField.ValueChangedFcn = createCallbackFcn(app, @T2BrushEditFieldValueChanged, true);
            app.T2BrushEditField.Visible = 'off';
            app.T2BrushEditField.Tooltip = {'ROI brush size'};
            app.T2BrushEditField.Value = 2;
            app.LogLabel = uilabel(app.T2mapTab);
            app.LogLabel.Text = 'Log';
            app.LinearLabel = uilabel(app.T2mapTab);
            app.LinearLabel.Text = 'Linear';
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.XTickLabelRotationMode = 'manual';
            app.M0MapFig.YTick = [];
            app.M0MapFig.YTickLabelRotationMode = 'manual';
            app.M0MapFig.ZTickLabelRotationMode = 'manual';
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.XTickLabelRotationMode = 'manual';
            app.R2MapFig.YTick = [];
            app.R2MapFig.YTickLabelRotationMode = 'manual';
            app.R2MapFig.ZTickLabelRotationMode = 'manual';
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.M0ImageSelectionPanel = uipanel(app.M0R2Tab);
            app.M0ImageSelectionPanel.Title = 'Image selection';
            app.M0MapSlicesEditField = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.M0MapSlicesEditField.Limits = [1 Inf];
            app.M0MapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.M0MapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesEditFieldValueChanged, true);
            app.M0MapSlicesEditField.Tooltip = {'Slice'};
            app.M0MapSlicesEditField.Value = 1;
            app.M0MapSlicesSlider = uislider(app.M0ImageSelectionPanel);
            app.M0MapSlicesSlider.Limits = [1 200];
            app.M0MapSlicesSlider.MajorTicks = [];
            app.M0MapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @M0MapSlicesSliderValueChanged, true);
            app.M0MapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.M0MapSlicesSlider.Tooltip = {'Slice'};
            app.M0MapSlicesSlider.Value = 1;
            app.M0MapDynamicsEditField = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.M0MapDynamicsEditField.Limits = [1 Inf];
            app.M0MapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.M0MapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @M0MapDynamicsEditFieldValueChanged, true);
            app.M0MapDynamicsEditField.Tooltip = {'Dynamic'};
            app.M0MapDynamicsEditField.Value = 1;
            app.M0MapDynamicsSlider = uislider(app.M0ImageSelectionPanel);
            app.M0MapDynamicsSlider.Limits = [1 200];
            app.M0MapDynamicsSlider.MajorTicks = [];
            app.M0MapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @M0MapDynamicsSliderValueChanged, true);
            app.M0MapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.M0MapDynamicsSlider.Tooltip = {'Dynamic'};
            app.M0MapDynamicsSlider.Value = 1;
            app.M0MapSlicesEditFieldLabel = uilabel(app.M0ImageSelectionPanel);
            app.M0MapSlicesEditFieldLabel.Text = 'slice';
            app.M0MapDynamicsEditFieldLabel = uilabel(app.M0ImageSelectionPanel);
            app.M0MapDynamicsEditFieldLabel.Text = 'dynamic';
            app.M0ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.M0ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M0ColorMapButtonGroupSelectionChanged, true);
            app.M0ColorMapButtonGroup.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Interpreter = 'html';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.autumnButton2.Text = 'autumn';
            app.autumnButton2.Interpreter = 'html';
            app.winterButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.winterButton2.Text = 'winter';
            app.winterButton2.Interpreter = 'html';
            app.parulaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.parulaButton2.Text = 'parula';
            app.parulaButton2.Interpreter = 'html';
            app.naviaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.naviaButton2.Text = 'navia';
            app.naviaButton2.Interpreter = 'html';
            app.R2ColorMapButtonGroup = uibuttongroup(app.M0R2Tab);
            app.R2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @R2ColorMapButtonGroupSelectionChanged, true);
            app.R2ColorMapButtonGroup.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Interpreter = 'html';
            app.autumnButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Interpreter = 'html';
            app.winterButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.winterButton3.Text = 'winter';
            app.winterButton3.Interpreter = 'html';
            app.parulaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Interpreter = 'html';
            app.parulaButton3.Value = true;
            app.naviaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.naviaButton3.Text = 'navia';
            app.naviaButton3.Interpreter = 'html';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.M0ROIButton = uibutton(app.M0R2Tab, 'push');
            app.M0ROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.M0ROIButton.Tooltip = {'Cross-hair or ROI drawing'};
            app.M0ROIButton.Text = 'ROI';
            app.M0ROIEraseButton = uibutton(app.M0R2Tab, 'push');
            app.M0ROIEraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.M0ROIEraseButton.Visible = 'off';
            app.M0ROIEraseButton.Tooltip = {'Erase ROI'};
            app.M0ROIEraseButton.Text = 'Erase';
            app.M0BrushEditFieldLabel = uilabel(app.M0R2Tab);
            app.M0BrushEditFieldLabel.Visible = 'off';
            app.M0BrushEditFieldLabel.Text = 'brush';
            app.M0BrushEditField = uieditfield(app.M0R2Tab, 'numeric');
            app.M0BrushEditField.Limits = [1 5];
            app.M0BrushEditField.ValueDisplayFormat = '%.0f';
            app.M0BrushEditField.ValueChangedFcn = createCallbackFcn(app, @M0BrushEditFieldValueChanged, true);
            app.M0BrushEditField.Visible = 'off';
            app.M0BrushEditField.Tooltip = {'ROI brush size'};
            app.M0BrushEditField.Value = 2;
            app.WaterFatTab = uitab(app.TabGroup3);
            app.WaterFatTab.Title = 'Water/Fat';
            app.WaterMapFig = uiaxes(app.WaterFatTab);
            app.WaterMapFig.XTick = [];
            app.WaterMapFig.XTickLabelRotationMode = 'manual';
            app.WaterMapFig.YTick = [];
            app.WaterMapFig.YTickLabelRotationMode = 'manual';
            app.WaterMapFig.ZTickLabelRotationMode = 'manual';
            app.FatMapFig = uiaxes(app.WaterFatTab);
            app.FatMapFig.XTick = [];
            app.FatMapFig.XTickLabelRotationMode = 'manual';
            app.FatMapFig.YTick = [];
            app.FatMapFig.YTickLabelRotationMode = 'manual';
            app.FatMapFig.ZTickLabelRotationMode = 'manual';
            app.WaterMapLabel = uilabel(app.WaterFatTab);
            app.WaterMapLabel.Text = 'Water';
            app.FatMapLabel = uilabel(app.WaterFatTab);
            app.FatMapLabel.Text = 'Fat';
            app.WaterFatImageSelectionPanel = uipanel(app.WaterFatTab);
            app.WaterFatImageSelectionPanel.Title = 'Image selection';
            app.WaterFatMapSlicesEditField = uieditfield(app.WaterFatImageSelectionPanel, 'numeric');
            app.WaterFatMapSlicesEditField.Limits = [1 Inf];
            app.WaterFatMapSlicesEditField.ValueDisplayFormat = '%.0f';
            app.WaterFatMapSlicesEditField.ValueChangedFcn = createCallbackFcn(app, @WFmapSlicesEditFieldValueChanged, true);
            app.WaterFatMapSlicesEditField.Tooltip = {'Slice'};
            app.WaterFatMapSlicesEditField.Value = 1;
            app.WaterFatMapSlicesSlider = uislider(app.WaterFatImageSelectionPanel);
            app.WaterFatMapSlicesSlider.Limits = [1 200];
            app.WaterFatMapSlicesSlider.MajorTicks = [];
            app.WaterFatMapSlicesSlider.ValueChangedFcn = createCallbackFcn(app, @WFmapSlicesSliderValueChange, true);
            app.WaterFatMapSlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.WaterFatMapSlicesSlider.Tooltip = {'Slice'};
            app.WaterFatMapSlicesSlider.Value = 1;
            app.WaterFatMapDynamicsEditField = uieditfield(app.WaterFatImageSelectionPanel, 'numeric');
            app.WaterFatMapDynamicsEditField.Limits = [1 Inf];
            app.WaterFatMapDynamicsEditField.ValueDisplayFormat = '%.0f';
            app.WaterFatMapDynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @WFmapDynamicsEditFieldValueChanged, true);
            app.WaterFatMapDynamicsEditField.Tooltip = {'Dynamic'};
            app.WaterFatMapDynamicsEditField.Value = 1;
            app.WaterFatMapDynamicsSlider = uislider(app.WaterFatImageSelectionPanel);
            app.WaterFatMapDynamicsSlider.Limits = [1 200];
            app.WaterFatMapDynamicsSlider.MajorTicks = [];
            app.WaterFatMapDynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @WFmapDynamicsSliderValueChanged, true);
            app.WaterFatMapDynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.WaterFatMapDynamicsSlider.Tooltip = {'Dynamic'};
            app.WaterFatMapDynamicsSlider.Value = 1;
            app.WaterFatMapSlicesEditFieldLabel = uilabel(app.WaterFatImageSelectionPanel);
            app.WaterFatMapSlicesEditFieldLabel.Text = 'slice';
            app.WaterFatMapDynamicsEditFieldLabel = uilabel(app.WaterFatImageSelectionPanel);
            app.WaterFatMapDynamicsEditFieldLabel.Text = 'dynamic';
            app.WaterColorMapButtonGroup = uibuttongroup(app.WaterFatTab);
            app.WaterColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaterColorMapButtonGroupSelectionChanged, true);
            app.WaterColorMapButtonGroup.Title = 'Color map';
            app.grayButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.grayButton4.Text = 'gray';
            app.grayButton4.Interpreter = 'html';
            app.grayButton4.Value = true;
            app.autumnButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.autumnButton4.Text = 'autumn';
            app.autumnButton4.Interpreter = 'html';
            app.winterButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.winterButton4.Text = 'winter';
            app.winterButton4.Interpreter = 'html';
            app.parulaButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.parulaButton4.Text = 'parula';
            app.parulaButton4.Interpreter = 'html';
            app.naviaButton4 = uiradiobutton(app.WaterColorMapButtonGroup);
            app.naviaButton4.Text = 'navia';
            app.naviaButton4.Interpreter = 'html';
            app.FatColorMapButtonGroup = uibuttongroup(app.WaterFatTab);
            app.FatColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FatColorMapButtonGroupSelectionChanged, true);
            app.FatColorMapButtonGroup.Title = 'Color map';
            app.grayButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.grayButton5.Text = 'gray';
            app.grayButton5.Interpreter = 'html';
            app.grayButton5.Value = true;
            app.autumnButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.autumnButton5.Text = 'autumn';
            app.autumnButton5.Interpreter = 'html';
            app.winterButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.winterButton5.Text = 'winter';
            app.winterButton5.Interpreter = 'html';
            app.parulaButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.parulaButton5.Text = 'parula';
            app.parulaButton5.Interpreter = 'html';
            app.naviaButton5 = uiradiobutton(app.FatColorMapButtonGroup);
            app.naviaButton5.Text = 'navia';
            app.naviaButton5.Interpreter = 'html';
            app.FatImage = uiimage(app.WaterFatTab);
            app.WaterImage = uiimage(app.WaterFatTab);
            app.WaterImage.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.TabGroup2 = uitabgroup(app.T2mappUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.XTickLabelRotationMode = 'manual';
            app.RawDataFig.YTick = [];
            app.RawDataFig.YTickLabelRotationMode = 'manual';
            app.RawDataFig.ZTickLabelRotationMode = 'manual';
            app.RawImageMaskSettingsPanel = uipanel(app.RawImagesTab);
            app.RawImageMaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.RawImageMaskSettingsPanel);
            app.ThresholdSlider.Limits = [0 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [0 1024 2048 3072 4096 5120 6144 7168 8192 9216 10240 11264 12288 13312 14336 15360 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold value'};
            app.ErodeButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.RawImageMaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.RawImageMaskSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'threshold';
            app.ThresholdEditField = uieditfield(app.RawImageMaskSettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold value'};
            app.RawImageLabel = uilabel(app.RawImagesTab);
            app.RawImageLabel.Text = 'Image';
            app.RawImageSelectionPanel = uipanel(app.RawImagesTab);
            app.RawImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.SlicesEditField.Tooltip = {'Slice'};
            app.SlicesEditField.Value = 1;
            app.EchoesEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.EchoesEditField.Limits = [1 Inf];
            app.EchoesEditField.ValueDisplayFormat = '%.0f';
            app.EchoesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.EchoesEditField.Tooltip = {'Echo'};
            app.EchoesEditField.Value = 1;
            app.DynamicsEditField = uieditfield(app.RawImageSelectionPanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.DynamicsEditField.Tooltip = {'Dynamic'};
            app.DynamicsEditField.Value = 1;
            app.SlicesSlider = uislider(app.RawImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider.Tooltip = {'Slice'};
            app.SlicesSlider.Value = 1;
            app.EchoesSlider = uislider(app.RawImageSelectionPanel);
            app.EchoesSlider.Limits = [1 200];
            app.EchoesSlider.MajorTicks = [];
            app.EchoesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.EchoesSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.EchoesSlider.Tooltip = {'Echo'};
            app.EchoesSlider.Value = 1;
            app.DynamicsSlider = uislider(app.RawImageSelectionPanel);
            app.DynamicsSlider.Limits = [1 200];
            app.DynamicsSlider.MajorTicks = [];
            app.DynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.DynamicsSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider.Tooltip = {'Dynamic'};
            app.DynamicsSlider.Value = 1;
            app.SlicesEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.SlicesEditFieldLabel.Text = 'slice';
            app.EchoesEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.EchoesEditFieldLabel.Text = 'echo';
            app.DynamicsEditFieldLabel = uilabel(app.RawImageSelectionPanel);
            app.DynamicsEditFieldLabel.Text = 'dynamic';
            app.RawImage = uiimage(app.RawImagesTab);
            app.WindowSlider = uislider(app.RawImagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawImagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.AutoLWButton = uibutton(app.RawImagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window/level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WindowEditFieldLabel = uilabel(app.RawImagesTab);
            app.WindowEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelEditFieldLabel = uilabel(app.RawImagesTab);
            app.LevelEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.RecoParametersTab = uitab(app.TabGroup2);
            app.RecoParametersTab.Title = 'Reco parameters';
            app.FilteringPanel = uipanel(app.RecoParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.DeNoiseCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeNoiseCheckBoxLabel.Text = 'PCA de-noise';
            app.DeNoiseCheckBox = uicheckbox(app.FilteringPanel);
            app.DeNoiseCheckBox.Tooltip = {'PCA denoising'};
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseWindowEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeNoiseWindowEditField.Tooltip = {'PCA denoising window size (pixels)'};
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeRingCheckBoxLabel.Text = 'de-ring';
            app.DeRingCheckBox = uicheckbox(app.FilteringPanel);
            app.DeRingCheckBox.Tooltip = {'De-ringing filter'};
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Text = '';
            app.CSregularizationPanel = uipanel(app.RecoParametersTab);
            app.CSregularizationPanel.Title = 'CS regularization';
            app.WVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.WVxyzEditField.Value = 0.001;
            app.TVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.LLRxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVteEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVteEditField.Limits = [0 20];
            app.TVteEditField.ValueDisplayFormat = '%.4f';
            app.TVteEditField.Enable = 'off';
            app.TVteEditField.Tooltip = {'Total Variation regularization in TE dimension'};
            app.TVdynEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation regularization in dynamic dimension'};
            app.WVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.TVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.LLRxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.TVteEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVteEditFieldLabel.Tooltip = {'Total Variation regularization in TE dimension'};
            app.TVteEditFieldLabel.Text = 'TVte';
            app.TVdynEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.EchoSelectionPanel = uipanel(app.FitparametersTab);
            app.EchoSelectionPanel.Tooltip = {'Echo selection for fitting'};
            app.EchoSelectionPanel.Title = 'Echo selection';
            app.CheckBox_1 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.EchoSelectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.SliceSelectionPanel = uipanel(app.FitparametersTab);
            app.SliceSelectionPanel.Title = 'Slice selection';
            app.FirstSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.FirstSliceEditFieldLabel.Text = 'first slice';
            app.FirstSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Tooltip = {'First slice for fitting'};
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.LastSliceEditFieldLabel.Text = 'last slice';
            app.LastSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Tooltip = {'Last slice for fitting'};
            app.LastSliceEditField.Value = 1;
            app.GoodnessOfFitPanel = uipanel(app.FitparametersTab);
            app.GoodnessOfFitPanel.Title = 'Goodness of fit';
            app.RsquareEditFieldLabel = uilabel(app.GoodnessOfFitPanel);
            app.RsquareEditFieldLabel.Text = 'min R-square';
            app.RsquareEditField = uieditfield(app.GoodnessOfFitPanel, 'numeric');
            app.RsquareEditField.Limits = [0 1];
            app.RsquareEditField.ValueDisplayFormat = '%.2f';
            app.RsquareEditField.Enable = 'off';
            app.RsquareEditField.Tooltip = {'R-square cut-off value'};
            app.RsquareEditField.Value = 0.8;
            app.DynamicsSelectionPanel = uipanel(app.FitparametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.FirstDynamicEditFieldLabel.Text = 'first dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.FirstDynamicEditField.Enable = 'off';
            app.FirstDynamicEditField.Tooltip = {'First dynamic for fitting'};
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.LastDynamicEditFieldLabel.Text = 'last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.LastDynamicEditField.Enable = 'off';
            app.LastDynamicEditField.Tooltip = {'Last dynamic for fitting'};
            app.LastDynamicEditField.Value = 1;
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.SoundsPanel = uipanel(app.AppSettingsTab);
            app.SoundsPanel.Title = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.SoundsPanel);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.SoundsPanel);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.ColorsPanel = uipanel(app.AppSettingsTab);
            app.ColorsPanel.Title = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.ColorsPanel);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.TabGroup4 = uitabgroup(app.T2mappUIFigure);
            app.SequenceParametersTab = uitab(app.TabGroup4);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DataFileViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DataFileViewFieldLabel.Text = 'datafile';
            app.DataFileViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.DataFileViewField.Editable = 'off';
            app.DataFileViewField.Tooltip = {'Data file name'};
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TE0EditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TE0EditFieldLabel.Text = 'TE0 (ms)';
            app.TE0EditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TE0EditField.Limits = [0 Inf];
            app.TE0EditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TE0EditField.Tooltip = {'First echo time'};
            app.ESPEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.ESPEditFieldLabel.Text = 'ESP (ms)';
            app.ESPEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.ESPEditField.Limits = [0 Inf];
            app.ESPEditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.ESPEditField.Tooltip = {'Echo spacing'};
            app.EchoesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.EchoesViewFieldLabel.Text = '# echoes';
            app.EchoesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.EchoesViewField.Limits = [0 Inf];
            app.EchoesViewField.Editable = 'off';
            app.EchoesViewField.Tooltip = {'Number of echoes'};
            app.DynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DynamicsViewFieldLabel.Text = '# dynamics';
            app.DynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DynamicsViewField.Limits = [0 Inf];
            app.DynamicsViewField.Editable = 'off';
            app.DynamicsViewField.Tooltip = {'Numer of dynamics/repetitions'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field of view'};
            app.DimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DimxViewFieldLabel.Text = 'dimx';
            app.DimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DimxViewField.Limits = [0 Inf];
            app.DimxViewField.ValueDisplayFormat = '%.0f';
            app.DimxViewField.Editable = 'off';
            app.DimxViewField.Tooltip = {'Readout matrix size'};
            app.DimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.DimyViewFieldLabel.Text = 'dimy';
            app.DimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.DimyViewField.Limits = [0 Inf];
            app.DimyViewField.ValueDisplayFormat = '%.0f';
            app.DimyViewField.Editable = 'off';
            app.DimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '# slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Numer of slices / 2nd phase-encoding matrix size'};
            app.SLTViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SLTViewFieldLabel.Text = 'slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.Hyperlink = uihyperlink(app.T2mappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.T2mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T2mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T2mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T2mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2mappUIFigure)
        end
    end
end